classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OmijanieprzeszkdkulTab          matlab.ui.container.Tab
        Srty_5                          matlab.ui.control.NumericEditField
        jakiLabel                       matlab.ui.control.Label
        UsuwierszButton                 matlab.ui.control.Button
        Srty_4                          matlab.ui.control.NumericEditField
        ileLabel_2                      matlab.ui.control.Label
        DodajprzeszkodyButton           matlab.ui.control.Button
        imaksymalnyEditField            matlab.ui.control.NumericEditField
        imaksymalnyEditFieldLabel       matlab.ui.control.Label
        CzasobliczeredniEditField       matlab.ui.control.NumericEditField
        CzasobliczeredniEditFieldLabel  matlab.ui.control.Label
        Wykres                          matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        ObiektTab                       matlab.ui.container.Tab
        IteracjenasekundEditField       matlab.ui.control.NumericEditField
        IteracjenasekundEditFieldLabel  matlab.ui.control.Label
        NiebracpoduwagewlasnejpredkosciCheckBox  matlab.ui.control.CheckBox
        WykrywanieprzeszkdSpinner       matlab.ui.control.Spinner
        WykrywanieprzeszkdSpinnerLabel  matlab.ui.control.Label
        MaksymalneprzypieszenieEditField  matlab.ui.control.NumericEditField
        MaksymalneprzypieszenieEditFieldLabel  matlab.ui.control.Label
        skretEditField                  matlab.ui.control.NumericEditField
        skretEditFieldLabel             matlab.ui.control.Label
        SztucznepolepotencjawTab        matlab.ui.container.Tab
        WzmocnienieodpychaniaEditField  matlab.ui.control.NumericEditField
        WzmocnienieodpychaniaEditFieldLabel  matlab.ui.control.Label
        WzmocnienieprzyciganiaEditField  matlab.ui.control.NumericEditField
        WzmocnienieprzyciganiaEditFieldLabel  matlab.ui.control.Label
        LogikarozmytaTab                matlab.ui.container.Tab
        OdrozmywanieDropDown            matlab.ui.control.DropDown
        OdrozmywanieDropDownLabel       matlab.ui.control.Label
        metodaandDropDown               matlab.ui.control.DropDown
        metodaandDropDownLabel          matlab.ui.control.Label
        ATab                            matlab.ui.container.Tab
        BezpiecznaodlegoEditField       matlab.ui.control.NumericEditField
        BezpiecznaodlegoEditFieldLabel  matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        EditField_5                     matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        WczytajButton                   matlab.ui.control.Button
        ZapisButton                     matlab.ui.control.Button
        testButton                      matlab.ui.control.Button
        drogaEditField                  matlab.ui.control.NumericEditField
        drogaEditFieldLabel             matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        PokazdrogeButton                matlab.ui.control.Button
        KolejnaiteracjaButton           matlab.ui.control.Button
        pokapozycjeButton               matlab.ui.control.Button
        WyczyButton                     matlab.ui.control.Button
        Srty_2                          matlab.ui.control.NumericEditField
        ileLabel                        matlab.ui.control.Label
        NarysujprzeszkodyButton         matlab.ui.control.Button
        StworzprzeszkodyButton          matlab.ui.control.Button
        najbliejEditField               matlab.ui.control.NumericEditField
        najbliejEditFieldLabel          matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        IteracjeEditField               matlab.ui.control.NumericEditField
        IteracjeEditFieldLabel          matlab.ui.control.Label
        DystansEditField                matlab.ui.control.NumericEditField
        DystansEditFieldLabel           matlab.ui.control.Label
        kolorDropDown                   matlab.ui.control.DropDown
        kolorDropDownLabel              matlab.ui.control.Label
        OblicztrajektorieButton         matlab.ui.control.Button
        kncz                            matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        kncy                            matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        kncx                            matlab.ui.control.NumericEditField
        KoniecxEditFieldLabel           matlab.ui.control.Label
        Srtz                            matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        Srty                            matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        Srtx                            matlab.ui.control.NumericEditField
        StartxEditFieldLabel            matlab.ui.control.Label
        NarysowaprzeszkodyCheckBox      matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        Metoda                          matlab.ui.control.DropDown
        MetodaDropDownLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        OmijanieinnychUAVTab            matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        OglneTab                        matlab.ui.container.Tab
        WykrywanieprzeszkdSpinner_2     matlab.ui.control.Spinner
        WykrywanieprzeszkdSpinner_2Label  matlab.ui.control.Label
        Metoda_2                        matlab.ui.control.DropDown
        MetodaDropDownLabel_2           matlab.ui.control.Label
        IteracjenasekundEditField_2     matlab.ui.control.NumericEditField
        IteracjenasekundEditField_2Label  matlab.ui.control.Label
        MaksymalneprzypieszenieEditField_2  matlab.ui.control.NumericEditField
        MaksymalneprzypieszenieEditField_2Label  matlab.ui.control.Label
        skretEditField_2                matlab.ui.control.NumericEditField
        skretEditField_2Label           matlab.ui.control.Label
        minodlegomidzyuavEditField      matlab.ui.control.NumericEditField
        minodlegomidzyuavEditFieldLabel  matlab.ui.control.Label
        SztucznepolapotencjawTab        matlab.ui.container.Tab
        WzmocnienieprzyciganiaEditField_2  matlab.ui.control.NumericEditField
        WzmocnienieprzyciganiaEditField_2Label  matlab.ui.control.Label
        WzmocnienieodpychaniaEditField_2  matlab.ui.control.NumericEditField
        WzmocnienieodpychaniaEditField_2Label  matlab.ui.control.Label
        logikarozmytaTab                matlab.ui.container.Tab
        OdrozmywanieDropDown_2          matlab.ui.control.DropDown
        OdrozmywanieDropDown_2Label     matlab.ui.control.Label
        ATab_2                          matlab.ui.container.Tab
        BezpiecznaodlegoEditField_2     matlab.ui.control.NumericEditField
        BezpiecznaodlegoEditField_2Label  matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        EditField_7                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        UsunwierszButton                matlab.ui.control.Button
        NiebracpoduwagewlasnejpredkosciCheckBox_2  matlab.ui.control.CheckBox
        DropDown_3                      matlab.ui.control.DropDown
        Label_5                         matlab.ui.control.Label
        LosujButton                     matlab.ui.control.Button
        EditField_6                     matlab.ui.control.EditField
        EditField_6Label                matlab.ui.control.Label
        WczytajButton_2                 matlab.ui.control.Button
        ZapisButton_2                   matlab.ui.control.Button
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        KolejnaiteracjaButton_2         matlab.ui.control.Button
        pokapozycjeButton_2             matlab.ui.control.Button
        WyczyButton_2                   matlab.ui.control.Button
        kolorDropDown_2                 matlab.ui.control.DropDown
        kolorDropDown_2Label            matlab.ui.control.Label
        OblicztrajektorieButton_2       matlab.ui.control.Button
        DodajobiektButton               matlab.ui.control.Button
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_4Label                 matlab.ui.control.Label
        PrognozaCheckBox_2              matlab.ui.control.CheckBox
        kncz_2                          matlab.ui.control.NumericEditField
        zEditField_2Label_2             matlab.ui.control.Label
        kncy_2                          matlab.ui.control.NumericEditField
        yEditField_2Label_2             matlab.ui.control.Label
        kncx_2                          matlab.ui.control.NumericEditField
        KoniecxEditFieldLabel_2         matlab.ui.control.Label
        Srtz_2                          matlab.ui.control.NumericEditField
        zEditFieldLabel_2               matlab.ui.control.Label
        Srty_3                          matlab.ui.control.NumericEditField
        yEditFieldLabel_2               matlab.ui.control.Label
        Srtx_2                          matlab.ui.control.NumericEditField
        StartxEditFieldLabel_2          matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        UIAxes_2                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        fis = readfis('gotowe.fis');
        Przeszkody=[]; % Description
        
        droga={};
        indexdroga=0; % Description
        zbiortras={}; % Description
    end

    methods (Access = private)
    
        function [aktualny, blisko] = trajprzyspieszenie(app,start,koniec,wejprzeszkody,ka,kr,ro,przewidywanie)

odlegoscx=koniec(1)-start(1);
odlegoscy=koniec(2)-start(2);
odlegoscz=koniec(3)-start(3);
odleglosc = sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
aktualny(1,1) = start(1);
aktualny(1,2) = start(2);
aktualny(1,3) = start(3);
index=1;
vx=0;
vy=0;
vz=0;
wektorp = wejprzeszkody(:,1:5);
roz = size(wektorp)
rodzaj = roz(1)
blisko=ro;
while (odleglosc>0.1)
    teraz(1) = aktualny(index,1);
    teraz(2) = aktualny(index,2);
    teraz(3) = aktualny(index,3);
    prognoza = teraz +8*[vx,vy,vz];
    if (przewidywanie==1)
    [silapx,silapy,silapz] = siladodatnia(app,prognoza,koniec,ka);
    else
        [silapx,silapy,silapz] = siladodatnia(app,teraz,koniec,ka);
    end
    silamsx=0;
    silamsy=0;
    silamsz=0;
    for i=1:rodzaj
        
        przeszkody=wektorp(i,:)
        [silamx,silamy,silamz,odlegloscp] = silaujemna(app,teraz,przeszkody,kr,ro);
        blisko=min(odlegloscp,blisko);
        silamsx=silamsx+silamx;
        silamsy=silamsy+silamy;
        silamsz=silamsz+silamz;
        
    end
    silax=silapx+silamsx;
    silay=silapy+silamsy;
    silaz=silapz+silamsz;
    silax=max(min(silax,0.4),-0.4);
    silay=max(min(silay,0.4),-0.4);
    silaz=max(min(silaz,0.4),-0.4);
    index=index+1;
    vx=vx+silax/20;
    vy=vy+silay/20;
    vz=vz+silaz/20;
    vx=max(min(vx,0.04),-0.04);
    vy=max(min(vy,0.04),-0.04);
    vz=max(min(vz,0.04),-0.04);
    aktualny(index,1) = aktualny(index-1,1) + vx;
    aktualny(index,2) = aktualny(index-1,2) + vy;
    aktualny(index,3) = aktualny(index-1,3) + vz;
    odlegoscx=koniec(1)-aktualny(index,1);
    odlegoscy=koniec(2)-aktualny(index,2);
    odlegoscz=koniec(3)-aktualny(index,3);
    odleglosc = sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
    wektorp(:,3:5) = wektorp(:,3:5)+wejprzeszkody(:,6:8);
    if (index>10000)
       odleglosc=0; 
    end
end
        end
        
        function [aktualny,blisko] = traj(app,start,koniec,wejprzeszkody,ka,kr,ro)

% odlegoscx=koniec(1)-start(1);
% odlegoscy=koniec(2)-start(2);
% odlegoscz=koniec(3)-start(3);
odleglosc = dystans(app,start,koniec)
aktualny(1,1) = start(1);
aktualny(1,2) = start(2);
aktualny(1,3) = start(3);
teraz(1) = aktualny(1,1);
teraz(2) = aktualny(1,2);
teraz(3) = aktualny(1,3);
index=1;
wektorp = wejprzeszkody(:,1:5);
roz = size(wektorp)
rodzaj = roz(1)
blisko=ro;
while (odleglosc>0.1)
    
    [silapx,silapy,silapz] = siladodatnia(app,teraz,koniec,ka);
    silamsx=0;
    silamsy=0;
    silamsz=0;
    for i=1:rodzaj
        

            przeszkody=wektorp(i,:)
            [silamx,silamy,silamz,odlegloscp] = silaujemna(app,teraz,przeszkody,kr,ro);
            blisko=min(odlegloscp,blisko);
            silamsx=silamsx+silamx;
            silamsy=silamsy+silamy;
            silamsz=silamsz+silamz;
        
    end
    silax=silapx+silamsx;
    silay=silapy+silamsy;
    silaz=silapz+silamsz;
    silax=max(min(silax,1),-1);
    silay=max(min(silay,1),-1);
    silaz=max(min(silaz,1),-1);
    index=index+1;
    aktualny(index,1) = aktualny(index-1,1) + silax;
    aktualny(index,2) = aktualny(index-1,2) + silay;
    aktualny(index,3) = aktualny(index-1,3) + silaz;
%     odlegoscx=koniec(1)-aktualny(index,1);
%     odlegoscy=koniec(2)-aktualny(index,2);
%     odlegoscz=koniec(3)-aktualny(index,3);
    teraz(1) = aktualny(index,1);
    teraz(2) = aktualny(index,2);
    teraz(3) = aktualny(index,3);
    wektorp(:,3:5) = wektorp(:,3:5)+wejprzeszkody(:,6:8);
    odleglosc = dystans(app,teraz,koniec);
    if (index>10000)
       odleglosc=0; 
    end
end
        end
        
        function wyj = stworzprzeszkody(app,ile)
flaga= 1;
wyj=[];
for i=1:ile
    while(flaga)
       x = rand()*6+2 ;
       y = rand()*6+2 ;
       z = rand()*6+2  ;
       r = rand()*1 ;
%        vx = rand()*0.1-0.05;
%        vy = rand()*0.1-0.05;
%        vz = rand()*0.1-0.05;
       if (((r+0.5) <x^2+y^2+z^2)&&((r+0.5) <(x-10)^2+(y-10)^2+(z-10)^2))
           flaga = 0;
       end
    end
    flaga = 1;
   wyj = [wyj;i,r,x,y,z];
end
% i=0;
% wyj=[];
% for y=2:2:8
%     for z=2:2:8
%         for x=2:2:8
%         i=i+1;
% %         x=3;
%         r=0.5;
%         wyj = [wyj;i,r,x,y,z,0,0,0];
%         end
%     end
% end
% for x=-3:0.5:3
%     for z=-3:0.5:3
%         i=i+1;
%         y=3;
%         r=1/4;
%         wyj = [wyj;i,r,x,y,z,0,0,0];
%     end
% end
% for x=-3:0.5:3
%     for z=-3:0.5:3
%         i=i+1;
%         y=-3;
%         r=1/4;
%         wyj = [wyj;i,r,x,y,z,0,0,0];
%     end
% end
% for y=-3:0.5:3
%     for x=-3:0.5:3
%         i=i+1;
%         z=3;
%         r=1/4;
%         wyj = [wyj;i,r,x,y,z,0,0,0];
%     end
% end
% for y=-3:0.5:3
%     for x=-3:0.5:3
%         i=i+1;
%         z=-3;
%         r=1/4;
%         wyj = [wyj;i,r,x,y,z,0,0,0];
%     end
% end
        end

        
        function [silax,silay,silaz,odleglosc] = silaujemna(app,aktualne,przeszkoda,kr,ro)


% punkt(1) = przeszkoda(index,1);
% punkt(2) = przeszkoda(index,2);
% punkt(3) = przeszkoda(index,3);

odlegoscx=przeszkoda(3)-aktualne(1);
odlegoscy=przeszkoda(4)-aktualne(2);
odlegoscz=przeszkoda(5)-aktualne(3);
odleglosc = sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2) - przeszkoda(2);
odlegoscx = odlegoscx*odleglosc/sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
odlegoscy = odlegoscy*odleglosc/sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
odlegoscz = odlegoscz*odleglosc/sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);

if (odleglosc>ro)
    silax=0;
    silay=0;
    silaz=0;
else
    silax=(-kr*odlegoscx/(odleglosc^3))*((1/odleglosc)-(1/ro));
    silay=(-kr*odlegoscy/(odleglosc^3))*((1/odleglosc)-(1/ro));
    silaz=(-kr*odlegoscz/(odleglosc^3))*((1/odleglosc)-(1/ro));
end
        end
        
        function [silax,silay,silaz] = siladodatnia(app,aktualne,docelowe,ka) %ka - stała przyciągania - dobierana doświadczalnie
%   Detailed explanation goes here
odlegoscx=docelowe(1)-aktualne(1);
odlegoscy=docelowe(2)-aktualne(2);
odlegoscz=docelowe(3)-aktualne(3);
odlegloscpoziom = sqrt(odlegoscx^2+odlegoscy^2);
odleglosc = dystans(app,docelowe,aktualne);
if (odleglosc<1) % przypisuję wartości ze wzoru ka*(punkt docelowy- aktualny)
    silax = ka*odlegoscx;
    silay = ka*odlegoscy;
    silaz = ka*odlegoscz;
else %przypisuję maksymalną wartość ka z zachowaniem kątów
    sinuspion = odlegoscz/odleglosc ;
    cosinuspion= odlegloscpoziom/odleglosc ;
    sinuspoziom=odlegoscx/odlegloscpoziom;
    cosinuspoziom=odlegoscy/odlegloscpoziom;
    
    silax=ka*cosinuspion*sinuspoziom;
    silay=ka*cosinuspion*cosinuspoziom;
    silaz=ka*sinuspion;
end
    
        end
        
        function punkty = przeszkodakula(app,r,srodek,probki)
punkty = [];
x=srodek(1);
y=srodek(2);
z=srodek(3);

for i=x-r:probki:x+r
    for j=y-r:probki:y+r
        k=z+sqrt(r^2-((i-x)^2)-((j-y)^2));
        if (imag(k) == 0)
            punkty = [punkty;i,j,k];
        end
        k=z-sqrt(r^2-((i-x)^2)-((j-y)^2));
        if (imag(k) == 0)
            punkty = [punkty;i,j,k];   
        end
    end
end
        end
        
        function index = najblizszy(app,aktu,przesz)
s=size(przesz);
for i=1:s(1)
    odleglosc = sqrt((przesz(i,1)-aktu(1))^2+(przesz(i,2)-aktu(2))^2+(przesz(i,3)-aktu(3))^2);
    if (i==1)
        min = odleglosc;
        index = i;
    else
        if odleglosc < min
        min = odleglosc;
        index = i;
        end
    end

end
        end
        
        function odleglosc = dystans(app,aktu,przesz)
odleglosc = sqrt((przesz(1)-aktu(1))^2+(przesz(2)-aktu(2))^2+(przesz(3)-aktu(3))^2);
        end
        
        function [aktualny,blisko] = trajfuzzy(app,start,koniec,wejprzeszkody,ka,skret,ro,gotowe)

odlegoscx=koniec(1)-start(1);
odlegoscy=koniec(2)-start(2);
odlegoscz=koniec(3)-start(3);
odlegloscpoziom = sqrt(odlegoscx^2+odlegoscy^2);
odleglosc = sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
katpoziom=atan2(odlegoscy,odlegoscx);
katpion=atan2(odlegoscz,odlegloscpoziom);
aktualny(1,1) = start(1);
aktualny(1,2) = start(2);
aktualny(1,3) = start(3);
teraz(1) = aktualny(1,1);
teraz(2) = aktualny(1,2);
teraz(3) = aktualny(1,3);
index=1;
wektorp = wejprzeszkody(:,1:5);
roz = size(wektorp)
rodzaj = roz(1)
blisko=ro
while (odleglosc>0.1)
    katpoziomcel=atan2(odlegoscy,odlegoscx);
    katpioncel=atan2(odlegoscz,odlegloscpoziom);
    docelupoziom=katpoziomcel-katpoziom;
    docelupoziom = normakat(app,docelupoziom,pi/4);
    docelupion=katpioncel-katpion;
    docelupion = normakat(app,docelupion,pi/4);
    
    % kod na przeszkody
    widzianeprzeszkody=0;
    for i=1:rodzaj
            odlegoscxp=wektorp(i,3)-teraz(1);
            odlegoscyp=wektorp(i,4)-teraz(2);
            odlegosczp=wektorp(i,5)-teraz(3);
            odlegloscp = sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2) - wektorp(i,2);
            odlegoscxp = odlegoscxp*sqrt(odleglosc/sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2));
            odlegoscyp = odlegoscyp*sqrt(odleglosc/sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2));
            odlegosczp = odlegosczp*sqrt(odleglosc/sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2));
            odlegloscpoziomp = sqrt(odlegoscxp^2+odlegoscyp^2);
            katpoziomp=atan2(odlegoscyp,odlegoscxp);
            katpionp=atan2(odlegosczp,odlegloscpoziomp);
            doprzeszkodypoziom = katpoziomp-katpoziom ;
            doprzeszkodypoziom = normakat(app,doprzeszkodypoziom,pi/2);
            if ((doprzeszkodypoziom>=0)&&(doprzeszkodypoziom<0.2))
                doprzeszkodypoziom=0.2;
            end
            if ((doprzeszkodypoziom<0)&&(doprzeszkodypoziom>-0.2))
                doprzeszkodypoziom=-0.2;
            end
            doprzeszkodypion = katpionp-katpion ;
            doprzeszkodypion = normakat(app,doprzeszkodypion,pi/2);
            if ((doprzeszkodypion>=0)&&(doprzeszkodypion<0.2))
                doprzeszkodypion=0.2;
            end
            if ((doprzeszkodypion<0)&&(doprzeszkodypion>-0.2))
                doprzeszkodypion=-0.2;
            end
            if (odlegloscp<ro)
                blisko=min(odlegloscp,blisko)
                widzianeprzeszkody = widzianeprzeszkody + 1;
                input = [odlegloscp,doprzeszkodypoziom,doprzeszkodypion];
                output = evalfis(input,gotowe);
                baza(widzianeprzeszkody,1)=output(1);
                baza(widzianeprzeszkody,2)=output(2);
                baza(widzianeprzeszkody,3)=output(3);
            end
           
        
    end
    if (widzianeprzeszkody>0)
        predkoscprzeszkody = min(baza(1:widzianeprzeszkody,1));
        v= min(vdocelu,predkoscprzeszkody);
        unikpoziom=sum(baza(1:widzianeprzeszkody,2));
        unikpoziom=normakat(app,unikpoziom,skret);
        unikpion=sum(baza(1:widzianeprzeszkody,3));
        unikpion=normakat(app,unikpion,skret);
    else
        v= vdocelu;
        unikpoziom=0;
        unikpion=0;
        
    end
    
    % koniec kodu na przeskody
    katpoziom = katpoziom +docelupoziom + unikpoziom;
    katpion = katpion+docelupion + unikpion;
    
    %v = 0.1 %zależne od przeskod
    v=v/2;
    vx=v*cos(katpion)*cos(katpoziom);
    vy=v*cos(katpion)*sin(katpoziom);
    vz=v*sin(katpion);
    index=index+1;
    aktualny(index,1) = aktualny(index-1,1) + vx;
    aktualny(index,2) = aktualny(index-1,2) + vy;
    aktualny(index,3) = aktualny(index-1,3) + vz;
     odlegoscx=koniec(1)-aktualny(index,1);
     odlegoscy=koniec(2)-aktualny(index,2);
     odlegoscz=koniec(3)-aktualny(index,3);
     odlegloscpoziom = sqrt(odlegoscx^2+odlegoscy^2);
    teraz(1) = aktualny(index,1);
    teraz(2) = aktualny(index,2);
    teraz(3) = aktualny(index,3);
    odleglosc = dystans(app,teraz,koniec);
    wektorp(:,3:5) = wektorp(:,3:5)+wejprzeszkody(:,6:8);
    
end
        end
        
        function kat = normakat(app,x,w)
    if (x>pi)
        x = x - 2*pi;
    end
    if (x<-pi)
        x = x + 2*pi;
    end
    
        kat = max(min(x,w),-w);
    
        end
        
        function wyj = rysujprzeszkody(app,wej)
           
wyj={};
try
    for i=1:max(wej(:,1))
           x = wej(i,3)  ;
           y = wej(i,4)  ;
           z = wej(i,5)   ;
           r = wej(i,2) ;
       q=przeszkodakula(app,r,[x y z],0.1);
       wyj{end+1}=q;
       
    
    end 
catch
end
        end
        
        function wyj = trasa(app,droga)
            q = size(droga);
            wyj=0;
            for i=1:(q-1)
                 wyj = wyj+sqrt((droga(i,1)-droga(i+1,1))^2+(droga(i,2)-droga(i+1,2))^2+(droga(i,3)-droga(i+1,3))^2);
                
            end
            
        end
        
        function [aktualny,blisko,kierunek,v] = trajkolejny(app,teraz,koniec,wektorp,ka,kr,ro,rodzajprzeszkod,vo,a,kierunek,skret,niebracpoduwageswojejpredkosci)

% odlegoscx=koniec(1)-start(1);
% odlegoscy=koniec(2)-start(2);
% odlegoscz=koniec(3)-start(3);
odleglosc = dystans(app,teraz,koniec)
roz = size(wektorp)
rodzaj = roz(1)
blisko=ro;
vmax=vo+a;
vmin=vo-a;
    [silapx,silapy,silapz] = siladodatnia(app,teraz,koniec,ka);
    silamsx=0;
    silamsy=0;
    silamsz=0;
    for i=1:rodzaj
        
        if (rodzajprzeszkod=="kula")
            przeszkody=wektorp(i,:);
        elseif (rodzajprzeszkod=="uav")
            przeszkody=[i,app.minodlegomidzyuavEditField.Value,wektorp(i,:)];
        end
            
            [silamx,silamy,silamz,odlegloscp] = silaujemna(app,teraz,przeszkody,kr,ro);
            blisko=min(odlegloscp,blisko);
            odlegoscxp=przeszkody(1,3)-teraz(1);
            odlegoscyp=przeszkody(1,4)-teraz(2);
            odlegosczp=przeszkody(1,5)-teraz(3);
            odlegloscp = sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2) - wektorp(1,2);
            odlegloscpomoc=(odlegoscxp^2+odlegoscyp^2+odlegosczp^2);
            odlegoscxp = odlegoscxp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            odlegoscyp = odlegoscyp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            odlegosczp = odlegosczp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            przeszkodasciana=teraz+[odlegoscxp odlegoscyp odlegosczp];
                if ((czybierzemypoduwage(app,teraz,vo,kierunek,przeszkodasciana,ro,ro/2))||(niebracpoduwageswojejpredkosci))
                  
                    silamsx=silamsx+silamx;
                    silamsy=silamsy+silamy;
                    silamsz=silamsz+silamz;
                end
    end
    silax=silapx+silamsx;
    silay=silapy+silamsy;
    silaz=silapz+silamsz;
    vcel=min(sqrt(silax^2+silay^2+silaz^2),1);
    v=max(min(vcel,vmax),vmin);
    if ((v<0.1)&&(v>odleglosc))
       v=0.1;
       v=max(min(v,vmax),vmin);
    end
    if ((silay==0)&&(silax==0))
        katpoziomcel=kierunek(1);
    else
        katpoziomcel=atan2(silay,silax);
    end
    if ((silay==0)&&(silax==0)&&(silaz==0))
        katpioncel=kierunek(2);
    else
        katpioncel=atan2(silaz,sqrt(silax^2+silay^2));
    end
    docelupoziom=katpoziomcel-kierunek(1);
    docelupoziom = normakat(app,docelupoziom,skret);
    docelupion=katpioncel-kierunek(2);
    docelupion = normakat(app,docelupion,skret);
    katpoziom=kierunek(1)+docelupoziom;
    katpion=kierunek(2)+docelupion;
    vx=v*cos(katpion)*cos(katpoziom);
    vy=v*cos(katpion)*sin(katpoziom);
    vz=v*sin(katpion);
    aktualny(1) = teraz(1) + vx;
    aktualny(2) = teraz(2) + vy;
    aktualny(3) = teraz(3) + vz;
    kierunek=[katpoziom,katpion];
%     silax=max(min(silax,1),-1);
%     silay=max(min(silay,1),-1);
%     silaz=max(min(silaz,1),-1);
%     aktualny(1) = teraz(1) + silax;
%     aktualny(2) = teraz(2) + silay;
%     aktualny(3) = teraz(3) + silaz;
%     odlegoscx=koniec(1)-aktualny(index,1);
%     odlegoscy=koniec(2)-aktualny(index,2);
        end
        
        function [aktualny,blisko,kierunek,v] = trajfuzzykolejny(app,teraz,kierunek,koniec,wejprzeszkody,skret,ro,gotowe,rodzajprzeszkod,a,vo,czasiteracji,niebracpoduwageswojejpredkosci)

    odlegoscx=koniec(1)-teraz(1);
    odlegoscy=koniec(2)-teraz(2);
    odlegoscz=koniec(3)-teraz(3);
    odlegloscpoziom = sqrt(odlegoscx^2+odlegoscy^2);
    odleglosc = sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
    katpoziom=kierunek(1);
    katpion=kierunek(2);
    roz = size(wejprzeszkody);
    rodzaj = roz(1);
    blisko=ro;
    vmax=vo+a;
    vmin=vo-a;

    katpoziomcel=atan2(odlegoscy,odlegoscx);

    katpioncel=atan2(odlegoscz,odlegloscpoziom);
    docelupoziom=(katpoziomcel-katpoziom)*czasiteracji;
    docelupoziom = normakat(app,docelupoziom,skret);
    docelupion=(katpioncel-katpion)*czasiteracji;
    docelupion = normakat(app,docelupion,skret);
    vdocelu=min(min(max(a,(1.5*a*odleglosc)),1),odleglosc);
    % kod na przeszkody
    widzianeprzeszkody=0;
    wspolczynnikwzanosci=3;
    for i=1:rodzaj
        if (rodzajprzeszkod=="kula")
            wektorp=wejprzeszkody(i,:);
        elseif (rodzajprzeszkod=="uav")
            wektorp=[i,app.minodlegomidzyuavEditField.Value,wejprzeszkody(i,:)];
        end
            odleglosciteracji=ro;
            odlegoscxp=wektorp(1,3)-teraz(1);
            odlegoscyp=wektorp(1,4)-teraz(2);
            odlegosczp=wektorp(1,5)-teraz(3);
            odlegloscp = sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2) - wektorp(1,2);
            odlegloscpomoc=(odlegoscxp^2+odlegoscyp^2+odlegosczp^2);
            odlegoscxp = odlegoscxp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            odlegoscyp = odlegoscyp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            odlegosczp = odlegosczp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            odlegloscpoziomp = sqrt(odlegoscxp^2+odlegoscyp^2);
            katpoziomp=atan2(odlegoscyp,odlegoscxp);
            katpionp=atan2(odlegosczp,odlegloscpoziomp);
            doprzeszkodypoziom = katpoziomp-katpoziom ;
            doprzeszkodypoziom = normakat(app,doprzeszkodypoziom,pi/2);
            if ((doprzeszkodypoziom>=0)&&(doprzeszkodypoziom<0.2))
                doprzeszkodypoziom=0.2;
            end
            if ((doprzeszkodypoziom<0)&&(doprzeszkodypoziom>-0.2))
                doprzeszkodypoziom=-0.2;
            end
            doprzeszkodypion = katpionp-katpion ;
            doprzeszkodypion = normakat(app,doprzeszkodypion,pi/2);
            if ((doprzeszkodypion>=0)&&(doprzeszkodypion<0.2))
                doprzeszkodypion=0.2;
            end
            if ((doprzeszkodypion<0)&&(doprzeszkodypion>-0.2))
                doprzeszkodypion=-0.2;
            end
            przeszkodasciana=teraz+[odlegoscxp odlegoscyp odlegosczp];
            if (odlegloscp<ro)
                if ((czybierzemypoduwage(app,teraz,vo,kierunek,przeszkodasciana,ro,ro/2))||(niebracpoduwageswojejpredkosci))
                odleglosciteracji=odlegloscp;
                blisko=min(odlegloscp,blisko);
                widzianeprzeszkody = widzianeprzeszkody + 1;
                input = [odlegloscp,doprzeszkodypoziom,doprzeszkodypion];
                output = evalfis(input,gotowe);
                baza(widzianeprzeszkody,1)=output(1);
                baza(widzianeprzeszkody,2)=output(2)*czasiteracji;
                baza(widzianeprzeszkody,3)=output(3)*czasiteracji;
                baza(widzianeprzeszkody,4)=odleglosciteracji;
                end
            end
           
        
    end
    if (widzianeprzeszkody>0)
        predkoscprzeszkody = min(baza(1:widzianeprzeszkody,1));
        v= min(vdocelu,predkoscprzeszkody);
        wspolczynnikwzanosci=min(baza(1:widzianeprzeszkody,4));
        unikpoziom=sum(baza(1:widzianeprzeszkody,2));
        unikpoziom=normakat(app,unikpoziom,skret);
        unikpion=sum(baza(1:widzianeprzeszkody,3));
        unikpion=normakat(app,unikpion,skret);
%             unikpoziom=0;
%         unikpion=0;
%         for i=1:widzianeprzeszkody
%             unikpoziom=baza(i,2)*min(0,wspolczynnikwzanosci-baza(i,4)+1)+unikpoziom;
%             unikpion=baza(i,3)*min(0,wspolczynnikwzanosci-baza(i,4)+1)+unikpion;
%         end
%         unikpoziom=normakat(app,unikpoziom,skret);
%         unikpion=normakat(app,unikpion,skret)
        
    else
        v= vdocelu;
        unikpoziom=0;
        unikpion=0;
        
    end
    if (wspolczynnikwzanosci<0)
       wspolczynnikwzanosci=0; 
    end
    
    
    docelupoziom=docelupoziom*(wspolczynnikwzanosci/3)^(1/6);
    docelupion=docelupion*(wspolczynnikwzanosci/3)^(1/6);
    % koniec kodu na przeskody
    katpoziom = katpoziom +docelupoziom + unikpoziom;
    katpion = katpion+docelupion + unikpion;
    
    %v = 0.1 %zależne od przeskod
%     v=v/2;
    v=max(min(v,vmax),vmin);
    vx=v*cos(katpion)*cos(katpoziom);
    vy=v*cos(katpion)*sin(katpoziom);
    vz=v*sin(katpion);
    aktualny(1) = teraz(1) + vx;
    aktualny(2) = teraz(2) + vy;
    aktualny(3) = teraz(3) + vz;
    kierunek=[katpoziom,katpion];


        end
        
        function [aktualny,blisko,kierunek,v,sciezka,poprzednieprzeszkody] = geometria(app,teraz,kierunek,koniec,wejprzeszkody,poprzednieprzeszkody,skret,ro,sciezka,rodzajprzeszkod,a,vo,metoda,niebracpoduwageswojejpredkosci,odstep)
katpoziom=kierunek(1);
katpion=kierunek(2);
roz = size(wejprzeszkody);
rodzaj = roz(1);
blisko=ro;
vmax=vo+a;
vmin=vo-a;
% kod na wybranie celu biorąc
if (isempty(sciezka)==1)
    cel=koniec;
else
   cel=sciezka(1,:) 
end
% koniec kodu na wybranie celu
%kod na przeszkody
%sprawdź czy przecina
przecina=0;
if (rodzajprzeszkod=="kula")
 %dodać bezpieczną odległość
    for i=1:rodzaj
        wektorp=wejprzeszkody(i,:);
%         elseif (rodzajprzeszkod=="uav")
%             wektorp=[i,app.minodlegomidzyuavEditField.Value,wejprzeszkody(i,:)];
%         end
        odlegoscxp=wektorp(3)-teraz(1);
            odlegoscyp=wektorp(4)-teraz(2);
            odlegosczp=wektorp(5)-teraz(3);
            odlegloscp = sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2) - wektorp(2);
            odlegloscpomoc=(odlegoscxp^2+odlegoscyp^2+odlegosczp^2);
            odlegoscxp = odlegoscxp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            odlegoscyp = odlegoscyp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            odlegosczp = odlegosczp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            if (odlegloscp<ro)
                blisko=min(odlegloscp,blisko);
                przeszkodasciana=teraz+[odlegoscxp odlegoscyp odlegosczp];
                if ((czybierzemypoduwage(app,teraz,vo,kierunek,przeszkodasciana,ro,ro/2))||(niebracpoduwageswojejpredkosci))
                    if (~ismember(wektorp(1),poprzednieprzeszkody))
                        
                        przecina=czyprzecina(app,teraz,cel,wektorp(3:5),wektorp(2)+odstep); %dodać bezpieczną odległość
                        wymiar=size(sciezka)
                        for j=1:(wymiar(1)-1) %tutaj wrócić   dsaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaa
                            przecina=max(czyprzecina(app,sciezka(j,:),sciezka(j+1,:),wektorp(3:5),wektorp(2)+odstep),przecina); %dodać bezpieczną odległość
                        end
                        if (isempty(sciezka)==0)
                            przecina=max(czyprzecina(app,sciezka(end,:),koniec,wektorp(3:5),wektorp(2)+odstep),przecina); %dodać bezpieczną odległość
                        end
                        if (przecina)
                            break %Aktualna ścieżka jest kolizyjna
                        else
                            poprzednieprzeszkody=[poprzednieprzeszkody,wektorp(1)];
                        end
                    end
                end
            end
           
        
    end
    if (przecina)
        %zrobić nową ścieżke
        listapunkty=[teraz,0]
        for i=1:rodzaj
            if (rodzajprzeszkod=="kula")
                wektorp=wejprzeszkody(i,:); %dodać bezpieczną odległość
            elseif (rodzajprzeszkod=="uav")
                wektorp=[i,odstep,wejprzeszkody(i,:)];
            end
            odlegoscxp=wektorp(1,3)-teraz(1);
            odlegoscyp=wektorp(1,4)-teraz(2);
            odlegosczp=wektorp(1,5)-teraz(3);
            odlegloscp = sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2) - wektorp(1,2);
            if (odlegloscp<ro)
                wektorp(2)=wektorp(2)+2*odstep;%wektorp(2)*1.4)
                nowepunkty=stworzpunkty(app,teraz,wektorp);
                listapunkty=[listapunkty;nowepunkty];
            end
            
        end
        listapunkty=[listapunkty;koniec (rodzaj+1)];
        poprzednieprzeszkody=listapunkty(:,4)';
        mozliwepolaczenia=sprpoloczenia(app,listapunkty,wejprzeszkody);
        if (metoda=="dikstrja")
            sciezka=dikstrja(app,listapunkty,mozliwepolaczenia);
        else
            sciezka=astar(app,listapunkty,mozliwepolaczenia);
        end
        sciezka(1,:)=[];
        cel=sciezka(1,:);
    end
else   
    for i=1:rodzaj
        wektorp=wejprzeszkody(i,:);
        odlegoscxp=wektorp(1)-teraz(1);
        odlegoscyp=wektorp(2)-teraz(2);
        odlegosczp=wektorp(3)-teraz(3);
        odlegloscp = sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2) -app.minodlegomidzyuavEditField.Value;
        odlegloscpomoc=(odlegoscxp^2+odlegoscyp^2+odlegosczp^2);
        odlegoscxp = odlegoscxp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            odlegoscyp = odlegoscyp*sqrt(abs(odlegloscp)/odlegloscpomoc);
            odlegosczp = odlegosczp*sqrt(abs(odlegloscp)/odlegloscpomoc);
        if (odlegloscp<ro)
             blisko=min(odlegloscp,blisko);
             przeszkodasciana=teraz+[odlegoscxp odlegoscyp odlegosczp];
                if ((czybierzemypoduwage(app,teraz,vo,kierunek,przeszkodasciana,ro,ro/2))||(app.NiebracpoduwagewlasnejpredkosciCheckBox.Value))
                  if(~isequal(wektorp,poprzednieprzeszkody(i,:)))
                     if (isempty(sciezka)==0)
                         przecina=czyomijacuav(app,[teraz;sciezka;cel],wektorp,poprzednieprzeszkody(i,:),odstep)
                     else
                        przecina=czyomijacuav(app,[teraz;cel],wektorp,poprzednieprzeszkody(i,:),odstep) %dodać bezpieczną odległość
                     end
                  else
                      przecina=0;
                  end
                 if (przecina)
                    break %Aktualna ścieżka jest kolizyjna 
                 end
                end
       end
    end
    if (przecina)
        %zrobić nową ścieżke
        listapunkty=[teraz,0]
        for i=1:rodzaj
            
                wektorp=wejprzeszkody(i,:); %dodać bezpieczną odległość
         
            odlegoscxp=wektorp(1,1)-teraz(1);
            odlegoscyp=wektorp(1,2)-teraz(2);
            odlegosczp=wektorp(1,3)-teraz(3);
            odlegloscp = sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2) - app.minodlegomidzyuavEditField.Value;
            if (odlegloscp<ro)
                odstep=app.minodlegomidzyuavEditField.Value+odstep*2;
                nowepunkty=stworzpunktyplaszczyzna(app,teraz,wektorp,wektorp-poprzednieprzeszkody(i,:),odstep);
                nr=linspace(i,i,7);
                nowepunkty(:,4)=nr;
                listapunkty=[listapunkty;nowepunkty];
            end
            
        end
        listapunkty=[listapunkty;koniec (rodzaj+1)];
        mozliwepolaczenia=sprpoloczeniaplaszczyzna(app,listapunkty,wejprzeszkody,poprzednieprzeszkody)
        if (metoda=="dikstrja")
            sciezka=dikstrja(app,listapunkty,mozliwepolaczenia);
        else
            sciezka=astar(app,listapunkty,mozliwepolaczenia);
        end
        sciezka(1,:)=[];
        cel=sciezka(1,:);
    end
    
    poprzednieprzeszkody=wejprzeszkody;
end
% koniec kodu na przeszkody

odlegoscx=cel(1)-teraz(1);
odlegoscy=cel(2)-teraz(2);
odlegoscz=cel(3)-teraz(3);
odlegloscpoziom = sqrt(odlegoscx^2+odlegoscy^2);
odleglosc = sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
app.DystansEditField.Value=odleglosc;
katpoziomcel=atan2(odlegoscy,odlegoscx);
katpioncel=atan2(odlegoscz,odlegloscpoziom);
docelupoziom=katpoziomcel-katpoziom;
docelupoziom = normakat(app,docelupoziom,skret);
docelupion=katpioncel-katpion;
docelupion = normakat(app,docelupion,skret);
katpoziom=katpoziom+docelupoziom;
katpion=katpion+docelupion;
vzakret=((pi-abs(docelupoziom)-abs(docelupion))/pi)^4;
v=min(min(min(max(a,(1.5*a*odleglosc)),1),vzakret),odleglosc);
v=max(min(v,vmax),vmin);
vx=v*cos(katpion)*cos(katpoziom);
vy=v*cos(katpion)*sin(katpoziom);
vz=v*sin(katpion);
aktualny(1) = teraz(1) + vx;
aktualny(2) = teraz(2) + vy;
aktualny(3) = teraz(3) + vz;
if ((dystans(app,cel,aktualny)<0.1)&&(isempty(sciezka)==0))
   sciezka(1,:)=[]; 
end
kierunek=[katpoziom,katpion];
        end
        
        function y = wagowa(x)
            y=abs(x);
            
        end
        
        function kierunkowe=rowanieprostej(app,teraz,potem)
odl=potem-teraz;
dyst=dystans(app,teraz,potem);
kierunkowe=odl/dyst;
        end
        
        function y=czyprzecina(app,teraz,potem,kula,r)
            kierunkowe=rowanieprostej(app,teraz,potem);
wspolczynnik=(kierunkowe*(teraz'-kula'))^2-dystans(app,teraz,kula)^2+r^2;
if (wspolczynnik<=0)
    y=0;
else
    roz1=sqrt(wspolczynnik)-(kierunkowe*(teraz'-kula'));
    roz2=-sqrt(wspolczynnik)-(kierunkowe*(teraz'-kula'));
    odleglosc=(potem(1)-teraz(1))/kierunkowe(1);
    if (((abs(odleglosc)>abs(roz1))&&(sign(odleglosc)==sign(roz1)))||((abs(odleglosc)>abs(roz2))&&(sign(odleglosc)==sign(roz1))))
        y=1;
    else
        y=0;
    end
end

        end
        
        
        function wyj = stworzpunkty(app,teraz,wektorp)
            kierunkowe=rowanieprostej(app,teraz,wektorp(3:5));
            if (kierunkowe(3)==0)
                kierunkowe(3)=0.0001
            end
            kierunkowe=kierunkowe/kierunkowe(3)
            a=kierunkowe(1);
            b=kierunkowe(2);
            c=kierunkowe(3);
            m=wektorp(3);
            n=wektorp(4)
            k=wektorp(5);
            r=wektorp(2);
            nr=linspace(wektorp(1),wektorp(1),8)
            % wspkw1=(a^2+1)
            % wsp1=(-2*m+2*a*d+a*k+2*a*b*n) 
            % stala1=(b^2+2)*n^2+(-2*n+2*b*d+b*k)*n+m^2+d^2+k*d+k^2-r^2
            % y=roots([wspkw1 wsp1 stala1]);
            
            
            if (c==1)
                %dla y=0
                x(1)=sqrt((r^2)/(a^2+1));
                y(1)=0;
                z(1)=-a*x(1)-b*y(1)+k;
                x(1)=x(1)+m;
                y(1)=y(1)+n
                
                x(2)=-sqrt((r^2)/(a^2+1));
                y(2)=0;
                z(2)=-a*x(2)-b*y(2)+k;
                x(2)=x(2)+m;
                y(2)=y(2)+n;
                x(3)=0;
                y(3)=sqrt((r^2)/(b^2+1));
                z(3)=-a*x(3)-b*y(3)+k;
                x(3)=x(3)+m;
                y(3)=y(3)+n
                x(4)=0;
                y(4)=-sqrt((r^2)/(b^2+1));;
                z(4)=-a*x(4)-b*y(4)+k;
                x(4)=x(4)+m;
                y(4)=y(4)+n;
                x(5)=sqrt((r^2)/(a^2+2*a*b+b^2+2));
                y(5)=x(5);
                z(5)=-a*x(5)-b*y(5)+k;
                x(5)=x(5)+m;
                y(5)=y(5)+n
                x(6)=sqrt((r^2)/(a^2-2*a*b+b^2+2));
                y(6)=-x(6);
                z(6)=-a*x(6)-b*y(6)+k;
                x(6)=x(6)+m;
                y(6)=y(6)+n
                x(7)=-sqrt((r^2)/(a^2+2*a*b+b^2+2));
                y(7)=x(7);
                z(7)=-a*x(7)-b*y(7)+k;
                x(7)=x(7)+m;
                y(7)=y(7)+n
                x(8)=-sqrt((r^2)/(a^2-2*a*b+b^2+2));
                y(8)=-x(8);
                z(8)=-a*x(8)-b*y(8)+k;
                x(8)=x(8)+m;
                y(8)=y(8)+n
                
            end
            wyj=[x',y',z',nr'];
        end
        
        function listapol = sprpoloczenia(app,listapunkty,wejprzeszkody)
            listapol=[];
            znaneprzeszkody=listapunkty(2,4);
            index=1;
            for i=3:(length(listapunkty)-1)
                if (znaneprzeszkody(index)~=listapunkty(i,4))
                   index=index+1;
                   znaneprzeszkody(index)=listapunkty(i,4);
                end
            end
            for i=1:(length(listapunkty)-1)
               for j=(i+1):length(listapunkty)
                    if (listapunkty(i,4)~=listapunkty(j,4))
                        przecina=0;
                        %ddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddd dać warunek
                        for k=1:length(znaneprzeszkody)
                            przecina=czyprzecina(app,listapunkty(i,1:3),listapunkty(j,1:3),wejprzeszkody(znaneprzeszkody(k),3:5),wejprzeszkody(znaneprzeszkody(k),2));
                            if (przecina)
                                break;
                                end
                            
                        end
                        if (przecina==0)
                            odleglosc=dystans(app,listapunkty(i,:),listapunkty(j,:));
                            listapol=[listapol;i j odleglosc];
                        end
                        
                    end
                    
               end
            end
            
        end
        
        function wyj = dikstrja(app,listpkt,listapol)
            m=true;
            n=true;
            iloscpktkoniec=1;
            iloscpol=length(listapol);
            iloscpkt=max(listapol(:,2));
            maksimum=iloscpkt
            
            for i=2:iloscpkt
               sciezka(i,1)=inf; 
               sciezka(i,3)=0;
            end
            gdzie=0;
            while(n)
               odleglosc=inf; 
               for i=1:iloscpkt
                   if ((sciezka(i,1)<odleglosc)&&(sciezka(i, 2) == 0))
                        odleglosc=sciezka(i,1);
                        gdzie = i;
                   end
               end
               sciezka(gdzie,2)=1;
               if (gdzie == maksimum)
                  while(m)
                     iloscpktkoniec= iloscpktkoniec+1;
                      gdzie=sciezka(gdzie,3);
                      if(gdzie==1)
                          m=0;
                          n=0;
                      end
                      
                  end
                   gdzie=maksimum
               else
                   for i=1:iloscpol
                        if ((listapol(i, 1) == gdzie) && ((listapol(i, 3) + sciezka(gdzie, 1)) < sciezka(listapol(i, 2), 1)))
                            sciezka(listapol(i, 2), 3) = gdzie; 
                            sciezka(listapol(i, 2), 1)  = listapol(i, 3) + sciezka(gdzie, 1);
                        end
                        if ((listapol(i, 2) == gdzie) && ((listapol(i, 3) + sciezka(gdzie, 1)) < sciezka(listapol(i, 1), 1)))
                            sciezka(listapol(i, 1), 3) = gdzie; 
                            sciezka(listapol(i, 1), 1)  = listapol(i, 3) + sciezka(gdzie, 1);
                        end
                   end
               end
            end
            for i=1:iloscpktkoniec
                kolejno(iloscpktkoniec-i+1)=gdzie;
                gdzie=sciezka(gdzie, 3);
            end
            wyj=[];
            for i=1:iloscpktkoniec
                wyj=[wyj;listpkt(kolejno(i),1:3)];
            end
        end
        function wyj = astar(app,listpkt,listapol)
            m=true;
            n=true;
            iloscpktkoniec=1;
            iloscpol=length(listapol);
            iloscpkt=max(listapol(:,2));
            maksimum=iloscpkt
            
            for i=2:iloscpkt
               sciezka(i,1)=inf; 
               sciezka(i,3)=0;
            end
            gdzie=0;
            while(n)
               odleglosc=inf; 
               for i=1:iloscpkt
                   if ((sciezka(i,1)<odleglosc)&&(sciezka(i, 2) == 0))
                        odleglosc=sciezka(i,1)+dystans(app,listpkt(i,1:3),listpkt(end,1:3));
                        gdzie = i;
                   end
               end
               sciezka(gdzie,2)=1;
               if (gdzie == maksimum)
                  while(m)
                     iloscpktkoniec= iloscpktkoniec+1;
                      gdzie=sciezka(gdzie,3);
                      if(gdzie==1)
                          m=0;
                          n=0;
                      end
                      
                  end
                   gdzie=maksimum
               else
                   for i=1:iloscpol
                        if ((listapol(i, 1) == gdzie) && ((listapol(i, 3) + sciezka(gdzie, 1)) < sciezka(listapol(i, 2), 1)))
                            sciezka(listapol(i, 2), 3) = gdzie; 
                            sciezka(listapol(i, 2), 1)  = listapol(i, 3) + sciezka(gdzie, 1);
                        end
                        if ((listapol(i, 2) == gdzie) && ((listapol(i, 3) + sciezka(gdzie, 1)) < sciezka(listapol(i, 1), 1)))
                            sciezka(listapol(i, 1), 3) = gdzie; 
                            sciezka(listapol(i, 1), 1)  = listapol(i, 3) + sciezka(gdzie, 1);
                        end
                   end
               end
            end
            for i=1:iloscpktkoniec
                kolejno(iloscpktkoniec-i+1)=gdzie;
                gdzie=sciezka(gdzie, 3);
            end
            wyj=[];
            for i=1:iloscpktkoniec
                wyj=[wyj;listpkt(kolejno(i),1:3)];
            end
        end

        function [kierunek] = prostaprostopodla(app,punktwej,kierunekwej,punkt)
%znajdź płaszyzny prostopadłą do wejścia przechodzą przez punkt
D=-(kierunekwej(1)*punkt(1)+kierunekwej(2)*punkt(2)+kierunekwej(3)*punkt(3));
% punkt przecięcia się płaszyzny z wej
t=-(D+kierunekwej(1)*punktwej(1)+kierunekwej(2)*punktwej(2)+kierunekwej(3)*punktwej(3))/(kierunekwej(1)^2+kierunekwej(2)^2+kierunekwej(3)^2);
punktprzeciecia(1)=punktwej(1)+kierunekwej(1)*t;
punktprzeciecia(2)=punktwej(2)+kierunekwej(2)*t;
punktprzeciecia(3)=punktwej(3)+kierunekwej(3)*t;
kierunek=punkt-punktprzeciecia;
kierunek(4)=-(kierunek(1)*punktwej(1)+kierunek(2)*punktwej(2)+kierunek(3)*punktwej(3));
        end
        
        function [punktprzeciecia] = punktxplaszczyznaxprosta(app,punktwej,kierunekprosta,kierunek)
t=-(kierunek(4)+kierunek(1)*punktwej(1)+kierunek(2)*punktwej(2)+kierunek(3)*punktwej(3))/(kierunek(1)*kierunekprosta(1)+kierunek(2)*kierunekprosta(2)+kierunek(3)*kierunekprosta(3));
punktprzeciecia(1)=punktwej(1)+kierunekprosta(1)*t;
punktprzeciecia(2)=punktwej(2)+kierunekprosta(2)*t;
punktprzeciecia(3)=punktwej(3)+kierunekprosta(3)*t;
        end
        
        function [odleglosc] = odlegloscprostapunkt(app,punktwej,kierunekwej,punkt)
D=-(kierunekwej(1)*punkt(1)+kierunekwej(2)*punkt(2)+kierunekwej(3)*punkt(3));
% punkt przecięcia się płaszyzny z wej
t=-(D+kierunekwej(1)*punktwej(1)+kierunekwej(2)*punktwej(2)+kierunekwej(3)*punktwej(3))/(kierunekwej(1)^2+kierunekwej(2)^2+kierunekwej(3)^2);
punktprzeciecia(1)=punktwej(1)+kierunekwej(1)*t;
punktprzeciecia(2)=punktwej(2)+kierunekwej(2)*t;
punktprzeciecia(3)=punktwej(3)+kierunekwej(3)*t;
odleglosc=dystans(app,punkt,punktprzeciecia);
        end
        
        function y = czyomijacuav(app,sciezka,przeszkoda,poprzedniaprzeszkoda,odstep)
kierunekprzeszkoda=przeszkoda-poprzedniaprzeszkoda;
iloscpkt=size(sciezka);
y=0;
for i=1:(iloscpkt(1)-1)
    kierunek=sciezka(i+1,:)-sciezka(i,:)
    kierunekplaszczyzny=prostaprostopodla(app,przeszkoda,kierunekprzeszkoda,sciezka(i,:));
    punktprzeciecia = punktxplaszczyznaxprosta(app,sciezka(i,:),kierunek,kierunekplaszczyzny);
    if (abs(punktprzeciecia)<inf)
        odleglosc = odlegloscprostapunkt(app,przeszkoda,kierunekprzeszkoda,punktprzeciecia);
        if (odleglosc<odstep)
            numerwspolrzednej=1;
            xmax=max(sciezka(i,1),sciezka(i+1,1));
            xmin=min(sciezka(i,1),sciezka(i+1,1));
            if (xmax==xmin)
               xmax=max(sciezka(i,2),sciezka(i+1,2));
               xmin=min(sciezka(i,2),sciezka(i+1,2)); 
               numerwspolrzednej=2; 
                if (xmax==xmin)
                  xmax=max(sciezka(i,3),sciezka(i+1,3));
                   xmin=min(sciezka(i,3),sciezka(i+1,3)); 
                   numerwspolrzednej=3;   
                   
                end
            end
            if ((punktprzeciecia(numerwspolrzednej)>xmin)&&(punktprzeciecia(numerwspolrzednej)<xmax))
               y=1;
               break
            end
        
        end
    end
end
        end
        
        function y = stworzpunktyplaszczyzna(app,teraz,przeszkoda,kierunekprzeszkoda,odstep)
kierunekplaszczyzna=prostaprostopodla(app,przeszkoda,kierunekprzeszkoda,teraz);
kierunekprostaprostopadladodwochprostych(1)=kierunekprzeszkoda(2)*kierunekplaszczyzna(3)-kierunekprzeszkoda(3)*kierunekplaszczyzna(2);
kierunekprostaprostopadladodwochprostych(2)=kierunekprzeszkoda(3)*kierunekplaszczyzna(1)-kierunekprzeszkoda(1)*kierunekplaszczyzna(3);
kierunekprostaprostopadladodwochprostych(3)=kierunekprzeszkoda(1)*kierunekplaszczyzna(2)-kierunekprzeszkoda(2)*kierunekplaszczyzna(1);
kierunekprzeszkoda=kierunekprzeszkoda/sqrt(kierunekprzeszkoda(1)^2+kierunekprzeszkoda(2)^2+kierunekprzeszkoda(3)^2);
kierunekprostaprostopadladodwochprostych=kierunekprostaprostopadladodwochprostych/sqrt(kierunekprostaprostopadladodwochprostych(1)^2+kierunekprostaprostopadladodwochprostych(2)^2+kierunekprostaprostopadladodwochprostych(3)^2);
y(1)=-kierunekprzeszkoda(1)*odstep+przeszkoda(1);
y(2)=-kierunekprzeszkoda(2)*odstep+przeszkoda(2);
y(3)=-kierunekprzeszkoda(3)*odstep+przeszkoda(3);
punktpomocniczy=punktxplaszczyznaxprosta(app,teraz,kierunekplaszczyzna,kierunekplaszczyzna);
odlegloscpomocnicz=(punktpomocniczy(1)-przeszkoda(1))/kierunekprzeszkoda(1);
for i=1:3
    odleglosc=odlegloscpomocnicz*i/2;
    punkt(1)=kierunekprzeszkoda(1)*odleglosc+przeszkoda(1);
    punkt(2)=kierunekprzeszkoda(2)*odleglosc+przeszkoda(2);
    punkt(3)=kierunekprzeszkoda(3)*odleglosc+przeszkoda(3);
    y(2*i,1)=kierunekprostaprostopadladodwochprostych(1)*odstep+punkt(1);
    y(2*i,2)=kierunekprostaprostopadladodwochprostych(2)*odstep+punkt(2);
    y(2*i,3)=kierunekprostaprostopadladodwochprostych(3)*odstep+punkt(3);
    y(2*i+1,1)=-kierunekprostaprostopadladodwochprostych(1)*odstep+punkt(1);
    y(2*i+1,2)=-kierunekprostaprostopadladodwochprostych(2)*odstep+punkt(2);
    y(2*i+1,3)=-kierunekprostaprostopadladodwochprostych(3)*odstep+punkt(3);
end
        end
        
        function listapol = sprpoloczeniaplaszczyzna(app,listapunkty,wejprzeszkody,poprzedniaprzeszkody)
            listapol=[];
            znaneprzeszkody=listapunkty(2,4);
            index=1;
            for i=3:(length(listapunkty)-1)
                if (znaneprzeszkody(index)~=listapunkty(i,4))
                   index=index+1;
                   znaneprzeszkody(index)=listapunkty(i,4);
                end
            end
            for i=1:(length(listapunkty)-1)
               for j=(i+1):length(listapunkty)
                    if (listapunkty(i,4)~=listapunkty(j,4))
                        przecina=0;
                        %ddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddddd dać warunek
                        for k=1:length(znaneprzeszkody)
                            przecina=czyomijacuav(app,[listapunkty(i,1:3);listapunkty(j,1:3)],wejprzeszkody(znaneprzeszkody(k),:),poprzedniaprzeszkody(znaneprzeszkody(k),:),0.8);
                            
                            if (przecina)
                                break;
                                end
                            
                        end
                        if (przecina==0)
                            odleglosc=dystans(app,listapunkty(i,:),listapunkty(j,:));
                            listapol=[listapol;i j odleglosc];
                        end
                        
                    end
                    
               end
            end
            
        end
        
        function y=czybierzemypoduwage(app,teraz,v,kierunek,przeszkoda,tp,r)
dp=max(r,tp*v);
dc=(dp-r)/2;
katpion=kierunek(2);
katpoziom=kierunek(1);
i(1)=cos(katpion)*cos(katpoziom);
i(2)=cos(katpion)*sin(katpoziom);
i(3)=sin(katpion);
srodek=teraz+dc*i;
d1=(dp+r)/2;
d2=sqrt((2*r^3)/(dp+r));
De=[d1,d2,d2];
Tr1=[cos(katpoziom) sin(katpoziom) 0; -sin(katpoziom) cos(katpoziom) 0; 0 0 1];
Tr2=[cos(katpion) 0 -sin(katpion); 0 1 0; sin(katpion) 0 cos(katpion)];
Ts=[1/De(1) 0 0; 0 1/De(2) 0; 0 0 1/De(3)];
T=Tr1*Tr2*Ts;
Pat=[0 0 0];%((teraz-srodek)*T);
Pdt=((przeszkoda-srodek)*T);
if (dystans(app,Pat,Pdt)<=1)
    y=1;
else
    y=0;
end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rotate3d(app.UIAxes,'on');
            app.testButton.Visible='off';
            app.DropDown_2.Visible='off';
            app.DropDown_4.Visible='off';
            app.Button.Visible='off';
            app.PrognozaCheckBox_2.Visible='off';
            app.DropDown_3.Visible='off';
            app.NiebracpoduwagewlasnejpredkosciCheckBox_2.Visible='off';
        end

        % Button pushed function: OblicztrajektorieButton
        function OblicztrajektorieButtonPushed(app, event)
            start = [app.Srtx.Value, app.Srty.Value, app.Srtz.Value]
% a2 = [5.3 5.3 8.3]
% 
% [b,c,d]=siladodatnia(a,a2,2)
%plot3(q(:,1),q(:,2),q(:,3),'*')
%najblizszy(a,q)
%qwerty=stworzprzeszkody(app,30);
% przeszkody= rysujprzeszkody(app,app.Przeszkody);%,app.Przeszkody);
przeszkody= app.Przeszkody;
koniec = [app.kncx.Value, app.kncy.Value, app.kncz.Value];
ro=app.WykrywanieprzeszkdSpinner.Value;
czascaly=0;
czaskmaks=0;
czasiteracji=1/app.IteracjenasekundEditField.Value
if (app.Metoda.Value=="potential fields")
    v=0;
    index=1;
    droga3=start;
    najblizej=100;
    odlegoscx=koniec(1)-start(1);
    odlegoscy=koniec(2)-start(2);
    odlegoscz=koniec(3)-start(3);
    odleglosc = sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
    odlegloscpoziom = sqrt(odlegoscx^2+odlegoscy^2);
    katpoziom=atan2(odlegoscy,odlegoscx);
    katpion=atan2(odlegoscz,odlegloscpoziom);
    kierunek=[katpoziom,katpion];
        while (odleglosc>0.1)
            czaspomoc=tic;
              index=index+1;
                      
                      teraz=droga3(index-1,:);
                      % Algorytm szukający punktu
                      [teraz,blisko,kierunek,v] = trajkolejny(app,teraz,koniec,przeszkody,app.WzmocnienieprzyciganiaEditField.Value,app.WzmocnienieodpychaniaEditField.Value,ro,'kula',v,app.MaksymalneprzypieszenieEditField.Value*czasiteracji,kierunek,app.skretEditField.Value*czasiteracji,app.NiebracpoduwagewlasnejpredkosciCheckBox.Value);
                      teraz=droga3(end,:)+(teraz-droga3(end,:))*czasiteracji;
                      droga3=[droga3;teraz];
                      odleglosc = dystans(app,teraz,koniec);
                      najblizej(index-1)=blisko;
                      czas=toc(czaspomoc);
                      czaskmaks=max(czas,czaskmaks);
                      czascaly=czascaly+czas;
         end
    blisko=min(najblizej);
end
if (app.Metoda.Value=="shortest road")
    %[droga3  blisko] = trajprzyspieszenie(app,start,koniec,przeszkody,app.WzmocnienieprzyciganiaEditField.Value,app.WzmocnienieodpychaniaEditField.Value,ro,app.NiebracpoduwagewlasnejpredkosciCheckBox.Value);
    v=0;
    index=1;
    droga3=start;
    najblizej=100;
    odlegoscx=koniec(1)-start(1);
    odlegoscy=koniec(2)-start(2);
    odlegoscz=koniec(3)-start(3);
    odlegloscpoziom = sqrt(odlegoscx^2+odlegoscy^2);
    odleglosc = sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
    katpoziom=atan2(odlegoscy,odlegoscx);
    katpion=atan2(odlegoscz,odlegloscpoziom);
    kierunek=[katpoziom,katpion];
    sciezka=[];
    poprzednieprzeszkody=[];
        while (odleglosc>0.1)
            czaspomoc=tic;
              index=index+1;  
                      teraz=droga3(index-1,:);
                      % Algorytm szukający punktu
                      [teraz,blisko,kierunek,v,sciezka,poprzednieprzeszkody] = geometria(app,teraz,kierunek,koniec,przeszkody,poprzednieprzeszkody,app.skretEditField.Value*czasiteracji,ro,sciezka,"kula",app.MaksymalneprzypieszenieEditField.Value*czasiteracji,v,app.DropDown_2.Value,app.NiebracpoduwagewlasnejpredkosciCheckBox.Value,app.minodlegomidzyuavEditField.Value);
                      teraz=droga3(end,:)+(teraz-droga3(end,:))*czasiteracji;
                      droga3=[droga3;teraz];
                      odleglosc = dystans(app,teraz,koniec);
                      najblizej(index-1)=blisko;
                      czas=toc(czaspomoc);
                      czaskmaks=max(czas,czaskmaks);
                      czascaly=czascaly+czas;
         end
    blisko=min(najblizej);
end
if (app.Metoda.Value=="logika rozmyta")
    v=0
    app.fis.defuzzMethod = app.OdrozmywanieDropDown.Value;
    app.fis.andMethod   = app.metodaandDropDown.Value;
    index=1;
    droga3=start;
    najblizej=100;
    odlegoscx=koniec(1)-start(1);
    odlegoscy=koniec(2)-start(2);
    odlegoscz=koniec(3)-start(3);
    odlegloscpoziom = sqrt(odlegoscx^2+odlegoscy^2);
    odleglosc = sqrt(odlegoscx^2+odlegoscy^2+odlegoscz^2);
    katpoziom=atan2(odlegoscy,odlegoscx);
    katpion=atan2(odlegoscz,odlegloscpoziom);
    kierunek=[katpoziom,katpion];
        while ((odleglosc>0.1)||(v>0.1))
            czaspomoc=tic;
              index=index+1;
                      
                      teraz=droga3(index-1,:);
                      % Algorytm szukający punktu
                      [teraz,blisko,kierunek,v] = trajfuzzykolejny(app,teraz,kierunek,koniec,przeszkody,app.skretEditField.Value*czasiteracji,ro,app.fis,"kula",app.MaksymalneprzypieszenieEditField.Value*czasiteracji,v,czasiteracji,app.NiebracpoduwagewlasnejpredkosciCheckBox.Value);
                      teraz=droga3(end,:)+(teraz-droga3(end,:))*czasiteracji;
                      droga3=[droga3;teraz];
                      odleglosc = dystans(app,teraz,koniec);
                      najblizej(index-1)=blisko;
                      czas=toc(czaspomoc);
                      czaskmaks=max(czas,czaskmaks);
                      czascaly=czascaly+czas;
         end
    blisko=min(najblizej);
end

% fis2 = fis
% fis2.defuzzMethod = 'bisector';
% droga4 = trajfuzzy(start,koniec,przeszkody,0.3,0.1,1.5,fis2)

if (app.NarysowaprzeszkodyCheckBox.Value)
    rozmiar = size(przeszkody)
 for i=1:rozmiar(1)
        [x,y,z] = sphere;
       surf(app.UIAxes,app.Przeszkody(i,2)*x+app.Przeszkody(i,3),app.Przeszkody(i,2)*y+app.Przeszkody(i,4),app.Przeszkody(i,2)*z+app.Przeszkody(i,5));
         hold(app.UIAxes,'on');
 end
end
kolor = app.kolorDropDown.Value;
if (app.DropDown.Value=="punkty")
    kolor=kolor+"*";
end
plot3(app.UIAxes,droga3(:,1),droga3(:,2),droga3(:,3),kolor);
hold(app.UIAxes,'on');
app.DystansEditField.Value = trasa(app,droga3);
app.IteracjeEditField.Value = length(droga3);
app.imaksymalnyEditField.Value=czaskmaks;
app.CzasobliczeredniEditField.Value=czascaly/length(droga3);
app.najbliejEditField.Value =blisko;
app.indexdroga=app.indexdroga+1;
app.droga{1,app.indexdroga}=droga3;
app.droga{2,app.indexdroga}=najblizej;
        end

        % Button pushed function: WyczyButton
        function WyczyButtonPushed(app, event)
            app.UIAxes.cla;
        end

        % Button pushed function: StworzprzeszkodyButton
        function StworzprzeszkodyButtonPushed(app, event)
            app.Przeszkody= stworzprzeszkody(app,app.Srty_2.Value);
            app.UITable.Data=app.Przeszkody;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.Przeszkody=app.UITable.Data;
        end

        % Button pushed function: NarysujprzeszkodyButton
        function NarysujprzeszkodyButtonPushed(app, event)
            przeszkody= rysujprzeszkody(app,app.Przeszkody);
            for i=1:length(przeszkody)
    q= przeszkody{i};
    if (length(q)>0);
       % plot3(app.UIAxes,q(:,1),q(:,2),q(:,3),'*')
       [x,y,z] = sphere;
       surf(app.UIAxes,app.Przeszkody(i,2)*x+app.Przeszkody(i,3),app.Przeszkody(i,2)*y+app.Przeszkody(i,4),app.Przeszkody(i,2)*z+app.Przeszkody(i,5))
         hold(app.UIAxes,'on')
    end
     
            end
            xlim(app.UIAxes,[0 11])
            ylim(app.UIAxes,[0 11])
            zlim(app.UIAxes,[0 11])
        end

        % Button pushed function: pokapozycjeButton
        function pokapozycjeButtonPushed(app, event)
            app.UIAxes.cla
            xlim(app.UIAxes,[0 11])
            ylim(app.UIAxes,[0 11])
            zlim(app.UIAxes,[0 11])
            pomoc=size(app.droga);
            if (app.drogaEditField.Value>pomoc(1))
                app.drogaEditField.Value=pomoc(1)
            end
            droga2=app.droga{1,app.drogaEditField.Value}
            pozycja = min(app.EditField.Value,length(droga2))
            plot3(app.UIAxes,droga2(pozycja,1),droga2(pozycja,2),droga2(pozycja,3),'*')
            najblizej = 100
            if (app.NarysowaprzeszkodyCheckBox.Value)
               rozmiar = size(app.Przeszkody)
                for i=1:rozmiar(1)
                        [x,y,z] = sphere;
                       surf(app.UIAxes,app.Przeszkody(i,2)*x+app.Przeszkody(i,3)+app.Przeszkody(i,6)*(pozycja-1),app.Przeszkody(i,2)*y+app.Przeszkody(i,4)+app.Przeszkody(i,7)*(pozycja-1),app.Przeszkody(i,2)*z+app.Przeszkody(i,5)+app.Przeszkody(i,8)*(pozycja-1))
                         hold(app.UIAxes,'on')
                         odleglosc=(((app.Przeszkody(i,3)+app.Przeszkody(i,6)*(pozycja-1))-droga2(pozycja,1))^2+((app.Przeszkody(i,4)+app.Przeszkody(i,7)*(pozycja-1))-droga2(pozycja,2))^2+((app.Przeszkody(i,5)+app.Przeszkody(i,8)*(pozycja-1))-droga2(pozycja,3))^2)
                         najblizej=min(najblizej,odleglosc)
                end
            end
            app.najbliejEditField.Value = najblizej
        end

        % Button pushed function: KolejnaiteracjaButton
        function KolejnaiteracjaButtonPushed(app, event)
            app.UIAxes.cla
            xlim(app.UIAxes,[0 11])
            ylim(app.UIAxes,[0 11])
            zlim(app.UIAxes,[0 11])
            app.EditField.Value = app.EditField.Value + app.EditField_2.Value
            pomoc=size(app.droga);
            if (app.drogaEditField.Value>pomoc(1))
                app.drogaEditField.Value=pomoc(1)
            end
            droga2=app.droga{1,app.drogaEditField.Value}
            pozycja = min(app.EditField.Value,length(droga2))
            plot3(app.UIAxes,droga2(pozycja,1),droga2(pozycja,2),droga2(pozycja,3),'*')
            najblizej = 100
            if (app.NarysowaprzeszkodyCheckBox.Value)
               rozmiar = size(app.Przeszkody)
                for i=1:rozmiar(1)
                        [x,y,z] = sphere;
                       surf(app.UIAxes,app.Przeszkody(i,2)*x+app.Przeszkody(i,3)+app.Przeszkody(i,6)*(pozycja-1),app.Przeszkody(i,2)*y+app.Przeszkody(i,4)+app.Przeszkody(i,7)*(pozycja-1),app.Przeszkody(i,2)*z+app.Przeszkody(i,5)+app.Przeszkody(i,8)*(pozycja-1))
                         hold(app.UIAxes,'on')
                         odleglosc=(((app.Przeszkody(i,3)+app.Przeszkody(i,6)*(pozycja-1))-droga2(pozycja,1))^2+((app.Przeszkody(i,4)+app.Przeszkody(i,7)*(pozycja-1))-droga2(pozycja,2))^2+((app.Przeszkody(i,5)+app.Przeszkody(i,8)*(pozycja-1))-droga2(pozycja,3))^2)
                         najblizej=min(najblizej,odleglosc)
                end
            end
            app.najbliejEditField.Value = najblizej
        end

        % Button pushed function: PokazdrogeButton
        function PokazdrogeButtonPushed(app, event)
            kolor = app.kolorDropDown.Value;
            if (app.DropDown.Value=="punkty")
                kolor=kolor+"*";
            end
            droga3=app.droga{1,app.drogaEditField.Value}
            plot3(app.UIAxes,droga3(:,1),droga3(:,2),droga3(:,3),kolor);
            hold(app.UIAxes,'on');
            rotate3d(app.UIAxes,'on');
            app.DystansEditField.Value = trasa(app,droga3);
            app.IteracjeEditField.Value = length(droga3);
            app.najbliejEditField.Value =app.droga{2,min(app.drogaEditField.Value)};
        end

        % Button pushed function: DodajobiektButton
        function DodajobiektButtonPushed(app, event)
%             pomoc=app.WykrywanieprzeszkdSpinner_2.Value*10;
%             if (app.Metoda_2.Value=="potential fields")
%                 pomoc=pomoc + 1000*app.WzmocnienieprzyciganiaEditField_2.Value;
%                 pomoc=pomoc + 100000*app.WzmocnienieodpychaniaEditField_2.Value;
%             elseif (app.Metoda_2.Value=="shortest road")
%                 pomoc=pomoc + 1000*app.WzmocnienieprzyciganiaEditField_2.Value;
%                 pomoc=pomoc + 100000*app.WzmocnienieodpychaniaEditField_2.Value;
%                 pomoc=pomoc + 1000000*app.PrognozaCheckBox_2.Value;
%             elseif (app.Metoda_2.Value=="logika rozmyta")
%                 pomoc = pomoc + 100000*app.skretEditField_2.Value;
%                 if (app.OdrozmywanieDropDown_2.Value=='bisector')
%                     pomoc=pomoc+1000000;
%                 end
%             end
            
            
            data = app.UITable_2.Data;
%             if (isempty(data))
%                 lp=1;
%                 app.UITable_2.Data={lp,app.Srtx_2.Value,app.Srty_3.Value,app.Srtz_2.Value,app.kncx_2.Value,app.kncy_2.Value,app.kncz_2.Value,app.Metoda_2.Value,pomoc,app.kolorDropDown_2.Value}; 
% %             elseif (length(data(:,1))==1)
% %                 lp=2
% %                 C = app.UITable_2.Data
% %                 C{lp,1}=lp;
% %                 C{lp,2}=app.Srtx_2.Value;
% %                 C{lp,3}=app.Srty_3.Value;
% %                 C{lp,4}=app.Srtz_2.Value;
% %                 C{lp,5}=app.kncx_2.Value;
% %                 C{lp,6}=app.kncy_2.Value;
% %                 C{lp,7}=app.kncz_2.Value;
% %                 C{lp,8}=app.Metoda_2.Value;
% %                 C{lp,9}=0
% %                 
% %                 
% %                 app.UITable_2.Data=C;
%             else
                if (isempty(data))
                    lp=1
                else
                    a=cell2mat(data(:,1));
                    lp=max(a)+1;
                end
                C = data;
                C{lp,1}=lp;
                C{lp,2}=app.Srtx_2.Value;
                C{lp,3}=app.Srty_3.Value;
                C{lp,4}=app.Srtz_2.Value;
                C{lp,5}=app.kncx_2.Value;
                C{lp,6}=app.kncy_2.Value;
                C{lp,7}=app.kncz_2.Value;
                C{lp,8}=app.Metoda_2.Value;
                C{lp,9}=app.WykrywanieprzeszkdSpinner_2.Value;
                C{lp,10}=app.skretEditField_2.Value;
                C{lp,11}=app.MaksymalneprzypieszenieEditField_2.Value;
                C{lp,14}=app.NiebracpoduwagewlasnejpredkosciCheckBox_2.Value;
                if (C{lp,8}=="potential fields")
                    C{lp,12}=app.WzmocnienieprzyciganiaEditField_2.Value;
                    C{lp,13}=app.WzmocnienieodpychaniaEditField_2.Value;
%                     C{lp,14}='brak';
                elseif (C{lp,8}=="logika rozmyta")
                    C{lp,12}=app.OdrozmywanieDropDown_2.Value;
                    C{lp,13}=app.DropDown_4.Value;
%                     C{lp,14}='brak';
                else
                    C{lp,12}=app.DropDown_3.Value;
                    C{lp,13}=app.BezpiecznaodlegoEditField_2.Value;
%                     C{lp,14}='brak';
                end
                
                C{lp,20}=app.kolorDropDown_2.Value;
                
                
                app.UITable_2.Data=C;
%end
            
%             app.UITable_2.Data(lp,2)=app.Srtx_2.Value;
%             app.UITable_2.Data(lp,3)=app.Srty_3.Value;
%             app.UITable_2.Data(lp,4)=app.Srtz_2.Value;
%             app.UITable_2.Data(lp,5)=app.kncx_2.Value;
%             app.UITable_2.Data(lp,6)=app.kncy_2.Value;
%             app.UITable_2.Data(lp,7)=app.kncz_2.Value;
%             app.UITable_2.Data(lp,8)=string(app.Metoda_2.Value(1));
%             app.UITable.ColumnFormat(lp,8)=({'char'});
%             app.UITable_2.Data={C;lp,startx,starty,startz,koniecx,koniecy,koniecz,metoda,0,0}
            
        end

        % Button pushed function: OblicztrajektorieButton_2
        function OblicztrajektorieButton_2Pushed(app, event)
            data = app.UITable_2.Data;
            lp=max(cell2mat(data(:,1)));
            zbiorTrasa={};
            for i=1:lp
                ro(i)=data{i,9};
                skret(i)=data{i,10};
                amax(i)=data{i,11};
                start=[data{i,2},data{i,3},data{i,4}]
                zbiorTrasa{i}=start;
                koniec(i,1)=data{i,5};
                koniec(i,2)=data{i,6};
                koniec(i,3)=data{i,7};
                czasmaks(i)=0;
                czascaly(i)=0;
                iteracje(i)=0;
                najblizej(i)=inf;
                wtrasie(i)=1;
                katpoziom=atan2(koniec(i,2)-start(2),koniec(i,1)-start(1))
                    katpion=atan2(koniec(i,3)-start(3),sqrt((koniec(i,1)-start(1))^2+(koniec(i,2)-start(2))^2))
                    kierunek(i,1)=katpoziom;
                    kierunek(i,2)=katpion;
                    v(i)=0;
                    niebracpoduwageswojejpredkosci(i)=data{i,14};
                if (data{i,8}=="potential fields")
                    ka(i)=data{i,12};
                    kp(i)=data{i,13};
                elseif (data{i,8}=="shortest road")
                    odstep(i)=data{i,13};
                    sciezki{i}=[];
                    poprzednieprzeszkody{i}=[];
                end
            end
            index=1;
            flaga=0;
            czasiteracjistandard=1/app.IteracjenasekundEditField_2.Value;
           while (sum(wtrasie))
               przeszkody=[];
               for i=1:lp
                   uav=zbiorTrasa{i};
                   przeszkody(i,1)=uav(index,1);
                   przeszkody(i,2)=uav(index,2);
                   przeszkody(i,3)=uav(index,3);
               end
               index=index+1;
              for i=1:lp
                  if (wtrasie(i))
                      aktualny=zbiorTrasa{i};
                      teraz=aktualny(index-1,:);
                      przeszkodyteraz=przeszkody;
                      przeszkodyteraz(i, :)=[];
                      % Algorytm szukający punktu
                      if (data{i,8}=="potential fields")
                          czaspomoc=tic;
                        [teraz,blisko,kierunek(i,:),v(i)] = trajkolejny(app,teraz,koniec(i,:),przeszkodyteraz,ka(i),kp(i),ro(i),'uav',v(i),amax(i)*czasiteracjistandard,kierunek(i,:),skret(i)*czasiteracjistandard,niebracpoduwageswojejpredkosci(i));
                        czasiteracji=toc(czaspomoc);
                        czasmaks(i)=max(czasmaks(i),czasiteracji);
                        czascaly(i)=czasiteracji+czascaly(i);
                        najblizej(i)=min(najblizej(i),blisko);
                      elseif (data{i,8}=="logika rozmyta")
                          
                          app.fis.defuzzMethod=data{i,12};
                          czaspomoc=tic;
                          [teraz,blisko,kierunek(i,:),v(i)] = trajfuzzykolejny(app,teraz,kierunek(i,:),koniec(i,:),przeszkodyteraz,skret(i)*czasiteracjistandard,ro(i),app.fis,'uav',amax(i)*czasiteracjistandard,v(i),czasiteracjistandard,niebracpoduwageswojejpredkosci(i));
                          czasiteracji=toc(czaspomoc);
                        czasmaks(i)=max(czasmaks(i),czasiteracji);
                        czascaly(i)=czasiteracji+czascaly(i);
                        najblizej(i)=min(najblizej(i),blisko);
                      else
                          if(flaga)
                              czaspomoc=tic;
                              [teraz,blisko,kierunek(i,:),v(i),sciezki{i},poprzednieprzeszkody{i}] = geometria(app,teraz,kierunek(i,:),koniec(i,:),przeszkodyteraz,poprzednieprzeszkody{i},skret(i)*czasiteracjistandard,ro(i),sciezki{i},"uav",amax(i)*czasiteracjistandard,v(i),'astar',niebracpoduwageswojejpredkosci(i),odstep(i));
                              czasiteracji=toc(czaspomoc);
                        czasmaks(i)=max(czasmaks(i),czasiteracji);
                        czascaly(i)=czasiteracji+czascaly(i);
                        najblizej(i)=min(najblizej(i),blisko);
                          else
                              poprzednieprzeszkody{i}=przeszkodyteraz;
                              
                          end
                      end
                      teraz=aktualny(end,:)+(teraz-aktualny(end,:))*czasiteracjistandard;
                      aktualny=[aktualny;teraz];
                      zbiorTrasa{i}=aktualny;
                      odleglosc = dystans(app,teraz,koniec(i,:));
                      if (odleglosc<0.1)
                          wtrasie(i)=0;
                      end
                  else
                      if (iteracje(i)==0)
                          iteracje(i)=index;
                      end
                      aktualny=zbiorTrasa{i};
                      aktualny=[aktualny;aktualny(index-1,:)];
                      zbiorTrasa{i}=aktualny;
                  end
              end
              flaga=1;
           end
           for i=1:lp
               kolor=string(data{i,end})+'*';
               droga3=zbiorTrasa{i};
               plot3(app.UIAxes_2,droga3(:,1),droga3(:,2),droga3(:,3),kolor);
               hold(app.UIAxes_2,'on');
               app.UITable_2.Data{i,15}=trasa(app,droga3);
               app.UITable_2.Data{i,16}=najblizej(i);
               if (iteracje(i)==0)
                  app.UITable_2.Data{i,17}=index;
                  iteracje(i)=index;
               else
                    app.UITable_2.Data{i,17}=iteracje(i);
               end
               app.UITable_2.Data{i,18}=czascaly(i)/iteracje(i);
               app.UITable_2.Data{i,19}=czasmaks(i);
           end
           rotate3d(app.UIAxes_2,'on');
           app.zbiortras=zbiorTrasa;
        end

        % Button pushed function: WyczyButton_2
        function WyczyButton_2Pushed(app, event)
            app.UIAxes_2.cla;
        end

        % Button pushed function: pokapozycjeButton_2
        function pokapozycjeButton_2Pushed(app, event)
            app.UIAxes_2.cla
            xlim(app.UIAxes_2,[0 11])
            ylim(app.UIAxes_2,[0 11])
            zlim(app.UIAxes_2,[0 11])
            %app.EditField.Value = app.EditField.Value + app.EditField_2.Value
            if (app.EditField_3.Value>length(app.zbiortras{1}))
                app.EditField_3.Value=length(app.zbiortras{1})
            end
            for i=1:length(app.zbiortras)
                droga2=app.zbiortras{i};
                kolor=string(app.UITable_2.Data(i,end))+'*';
                plot3(app.UIAxes_2,droga2(app.EditField_3.Value,1),droga2(app.EditField_3.Value,2),droga2(app.EditField_3.Value,3),kolor);
                hold(app.UIAxes_2,'on');
            end
%             najblizej = 100
%             if (app.NarysowaprzeszkodyCheckBox.Value)
%                rozmiar = size(app.Przeszkody)
%                 for i=1:rozmiar(1)
%                         [x,y,z] = sphere;
%                        surf(app.UIAxes,app.Przeszkody(i,2)*x+app.Przeszkody(i,3)+app.Przeszkody(i,6)*(pozycja-1),app.Przeszkody(i,2)*y+app.Przeszkody(i,4)+app.Przeszkody(i,7)*(pozycja-1),app.Przeszkody(i,2)*z+app.Przeszkody(i,5)+app.Przeszkody(i,8)*(pozycja-1))
%                          hold(app.UIAxes,'on')
%                          odleglosc=(((app.Przeszkody(i,3)+app.Przeszkody(i,6)*(pozycja-1))-droga2(pozycja,1))^2+((app.Przeszkody(i,4)+app.Przeszkody(i,7)*(pozycja-1))-droga2(pozycja,2))^2+((app.Przeszkody(i,5)+app.Przeszkody(i,8)*(pozycja-1))-droga2(pozycja,3))^2)
%                          najblizej=min(najblizej,odleglosc)
%                 end
%             end
        end

        % Button pushed function: KolejnaiteracjaButton_2
        function KolejnaiteracjaButton_2Pushed(app, event)
            app.UIAxes_2.cla
            xlim(app.UIAxes_2,[0 11])
            ylim(app.UIAxes_2,[0 11])
            zlim(app.UIAxes_2,[0 11])
            app.EditField_3.Value = app.EditField_3.Value + app.EditField_4.Value
            if (app.EditField_3.Value>length(app.zbiortras{1}))
                app.EditField_3.Value=length(app.zbiortras{1})
            end
            for i=1:length(app.zbiortras)
                droga2=app.zbiortras{i};
                kolor=string(app.UITable_2.Data(i,end))+'*';
                plot3(app.UIAxes_2,droga2(app.EditField_3.Value,1),droga2(app.EditField_3.Value,2),droga2(app.EditField_3.Value,3),kolor);
                hold(app.UIAxes_2,'on');
            end
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
%            droga3=[0 0 0];
%            koniec=[10 10 10];
%            odleglosc=dystans(app,[0 0 0],koniec);
%            teraz=droga3;
%            katpoziom=0;
%            katpion=0;
%            v=0;
%            while(odleglosc>0.1)
%             y=fminsearch(@(x) wagoway(x,teraz,koniec,v,[katpoziom katpion],[],0.2,pi,1,1,1),[0 0 0])%,[1 0 0;0 1 0; 0 0 1;-1 0 0;0 -1 0;0 0 -1],[min(0.2,1-v) pi pi,min(0.2,v),pi,pi])
%             v=v+y(1)
%             katpoziom=katpoziom+y(2);
%             katpion=katpion+y(2);
%                 vx=v*cos(katpion)*cos(katpoziom);
%                 vy=v*cos(katpion)*sin(katpoziom);
%                 vz=v*sin(katpion);
%                 teraz(1)=teraz(1)+vx;
%                 teraz(2)=teraz(2)+vy;
%                 teraz(3)=teraz(3)+vz;
%                 droga3=[droga3;teraz]
%                 odleglosc=dystans(app,teraz,koniec);
%            end
%            y;
%             function y = wagoway(x,teraz,koniec,v,kierunek,przeszkody,a,skret,kr,ka,ro)
%                 x=x/1000;
%                 katpoziom=kierunek(1);
%                 katpion=kierunek(2);
%                 vx=(v+x(1))*cos(katpion+x(3))*cos(katpoziom+x(2));
%                 vy=(v+x(1))*cos(katpion+x(3))*sin(katpoziom+x(2));
%                 vz=(v+x(1))*sin(katpion+x(3));
%                 pozycja(1)=vx+teraz(1);
%                 pozycja(2)=vy+teraz(2);
%                 pozycja(3)=vz+teraz(3);
% %                 if ((x(1)>a)||(max(abs(x(2)),abs(x(3)))>skret)||(v-x(1)<0)||(v+x(1)>10))
% %                     y=100000000;
% %                 else
%                     odleglosc=dystans(app,pozycja,koniec);
%                     y=odleglosc*kr;
%                     for i=1:length(przeszkody)
%                         wektorp=przeszkody(i,:);
%                         odlegoscxp=wektorp(1,3)-teraz(1);
%                         odlegoscyp=wektorp(1,4)-teraz(2);
%                         odlegosczp=wektorp(1,5)-teraz(3);
%                         odlegloscp = sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2) - wektorp(1,2);
% %                         odlegoscxp = odlegoscxp*sqrt(odlegloscp/sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2));
% %                         odlegoscyp = odlegoscyp*sqrt(odlegloscp/sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2));
% %                         odlegosczp = odlegosczp*sqrt(odlegloscp/sqrt(odlegoscxp^2+odlegoscyp^2+odlegosczp^2));
% %                         odlegloscpoziomp = sqrt(odlegoscxp^2+odlegoscyp^2);
% %                         katpoziomp=atan2(odlegoscyp,odlegoscxp);
% %                         katpionp=atan2(odlegosczp,odlegloscpoziomp);
%                         y=y+ka/odlegloscp^2*(1/odlegloscp-1/ro);
%                     end
%                 end
%             end
                       % Create a temporary figure with axes.
fig = figure;
fig.Visible = 'off';
figAxes = axes(fig);
% Copy all UIAxes children, take over axes limits and aspect ratio.            
allChildren = app.UIAxes.XAxis.Parent.Children;
copyobj(allChildren, figAxes)
figAxes.XLim = app.UIAxes.XLim;
figAxes.YLim = app.UIAxes.YLim;
figAxes.ZLim = app.UIAxes.ZLim;
figAxes.View  = app.UIAxes.View ;
figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
% Save as png and fig files.
saveas(fig, 'qweeqweqweqweqw', 'png');
savefig(fig, 'qweeqweqweqweqw2323');
% Delete the temporary figure.
delete(fig);
            
        end

        % Button pushed function: ZapisButton
        function ZapisButtonPushed(app, event)
            p=app.UITable.Data;
            sciezka=strcat(app.EditField_5.Value,'.mat');
            save(sciezka,'p')
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
            sciezka=strcat(app.EditField_5.Value,'.mat');
            try
            load(sciezka)
            app.UITable.Data=p; 
            app.Przeszkody=p;
            catch
            end
        end

        % Button pushed function: ZapisButton_2
        function ZapisButton_2Pushed(app, event)
            p=app.UITable_2.Data;
            sciezka=strcat(app.EditField_5.Value,'.mat');
            save(sciezka,'p')
        end

        % Button pushed function: WczytajButton_2
        function WczytajButton_2Pushed(app, event)
            try
            sciezka=strcat(app.EditField_5.Value,'.mat');
            load(sciezka)
            app.UITable_2.Data=p; 
            catch
            end
        end

        % Button pushed function: LosujButton
        function LosujButtonPushed(app, event)
            app.Srtx_2.Value = rand()*10;
            app.Srty_3.Value = rand()*10;
            app.Srtz_2.Value = rand()*10;
            app.kncx_2.Value = rand()*10;
            app.kncy_2.Value = rand()*10;
            app.kncz_2.Value = rand()*10;
        end

        % Button pushed function: Wykres
        function WykresButtonPushed(app, event)
            droga3=app.droga{1,app.drogaEditField.Value}
            blisko=app.droga{2,app.drogaEditField.Value}
            for i=1:length(droga3)
                dystanskoniec(i)=dystans(app,droga3(i,:),droga3(end,:))
               kolejno(i)=i; 
            end
            cla(app.UIAxes);
            plot(app.UIAxes,dystanskoniec,kolejno);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,[blisko 3],kolejno);
            hold(app.UIAxes,'off');
            rotate3d(app.UIAxes,'on');
            view(app.UIAxes,-90,90)
        end

        % Button pushed function: UsunwierszButton
        function UsunwierszButtonPushed(app, event)
            c=app.UITable_2.Data;
            rzad=size(c);
            pomoc=0;
            try
            for i=1:rzad(1)
                 if (i~=app.EditField_7.Value)
                     pomoc=pomoc+1;
                     for j=1:rzad(2)
                         if (j==1)
                            d{pomoc,j}=pomoc;
                         else
                            d{pomoc,j}=c{i,j}
                         end
                     end
                 end
            end
            app.UITable_2.Data=d;
            catch
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            fig = figure;
fig.Visible = 'off';
figAxes = axes(fig);
% Copy all UIAxes children, take over axes limits and aspect ratio.            
allChildren = app.UIAxes_2.XAxis.Parent.Children;
copyobj(allChildren, figAxes)
figAxes.XLim = app.UIAxes_2.XLim;
figAxes.YLim = app.UIAxes_2.YLim;
figAxes.ZLim = app.UIAxes_2.ZLim;
figAxes.View  = app.UIAxes_2.View ;
figAxes.DataAspectRatio = app.UIAxes_2.DataAspectRatio;
% Save as png and fig files.
saveas(fig, 'qweeqweqweqweqw', 'png');
savefig(fig, 'qweeqweqweqweqw2323');
% Delete the temporary figure.
delete(fig);
        end

        % Button pushed function: DodajprzeszkodyButton
        function DodajprzeszkodyButtonPushed(app, event)
            obstacles=app.UITable.Data;
            if (isempty(obstacles))
                obstacles=0;
            end
            pomoc=max(obstacles(:,1));
            for i=(pomoc+1):(pomoc+app.Srty_4.Value)
               obstacles(i,1)=i;
               obstacles(i,2)=5;
               obstacles(i,3)=5;
               obstacles(i,4)=5;
               obstacles(i,5)=5;
            end
            app.UITable.Data=obstacles;
        end

        % Button pushed function: UsuwierszButton
        function UsuwierszButtonPushed(app, event)
            c=app.UITable.Data;
            rzad=size(c);
            pomoc=0;
            try
            for i=1:rzad(1)
                 if (i~=app.Srty_5.Value)
                     pomoc=pomoc+1;
                     for j=1:rzad(2)
                         if (j==1)
                            d(pomoc,j)=pomoc;
                         else
                            d(pomoc,j)=c(i,j);
                         end
                     end
                 end
            end
            app.UITable.Data=d;
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OmijanieprzeszkdkulTab = uitab(app.TabGroup);
            app.OmijanieprzeszkdkulTab.Title = 'Omijanie przeszkód - kul';
            app.UIAxes = uiaxes(app.OmijanieprzeszkdkulTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MetodaDropDownLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.MetodaDropDownLabel.Text = 'Metoda';
            app.Metoda = uidropdown(app.OmijanieprzeszkdkulTab);
            app.Metoda.Items = {'potential fields', 'shortest road', 'logika rozmyta'};
            app.Metoda.Value = 'potential fields';
            app.UITable = uitable(app.OmijanieprzeszkdkulTab);
            app.UITable.ColumnName = {'lp'; 'promień'; 'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.NarysowaprzeszkodyCheckBox = uicheckbox(app.OmijanieprzeszkdkulTab);
            app.NarysowaprzeszkodyCheckBox.Text = 'Narysować przeszkody?';
            app.NarysowaprzeszkodyCheckBox.Value = true;
            app.StartxEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.StartxEditFieldLabel.Text = 'Start x';
            app.Srtx = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.yEditFieldLabel.Text = 'y';
            app.Srty = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.zEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.zEditFieldLabel.Text = 'z';
            app.Srtz = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.KoniecxEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.KoniecxEditFieldLabel.Text = 'Koniec x';
            app.kncx = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.kncx.Value = 10;
            app.yEditField_2Label = uilabel(app.OmijanieprzeszkdkulTab);
            app.yEditField_2Label.Text = 'y';
            app.kncy = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.kncy.Value = 10;
            app.zEditField_2Label = uilabel(app.OmijanieprzeszkdkulTab);
            app.zEditField_2Label.Text = 'z';
            app.kncz = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.kncz.Value = 10;
            app.OblicztrajektorieButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.OblicztrajektorieButton.ButtonPushedFcn = createCallbackFcn(app, @OblicztrajektorieButtonPushed, true);
            app.OblicztrajektorieButton.Text = 'Oblicz trajektorie';
            app.kolorDropDownLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.kolorDropDownLabel.Text = 'kolor';
            app.kolorDropDown = uidropdown(app.OmijanieprzeszkdkulTab);
            app.kolorDropDown.Items = {'r', 'y', 'm', 'g', 'c', 'b', 'w', 'k'};
            app.kolorDropDown.Value = 'r';
            app.DystansEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.DystansEditFieldLabel.Text = 'Dystans';
            app.DystansEditField = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.IteracjeEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.IteracjeEditFieldLabel.Text = 'Iteracje';
            app.IteracjeEditField = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.IteracjeEditField.Limits = [1 Inf];
            app.IteracjeEditField.Value = 1;
            app.Label = uilabel(app.OmijanieprzeszkdkulTab);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.OmijanieprzeszkdkulTab);
            app.DropDown.Items = {'linia', 'punkty'};
            app.DropDown.Value = 'linia';
            app.najbliejEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.najbliejEditFieldLabel.Text = 'najbliżej';
            app.najbliejEditField = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.StworzprzeszkodyButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.StworzprzeszkodyButton.ButtonPushedFcn = createCallbackFcn(app, @StworzprzeszkodyButtonPushed, true);
            app.StworzprzeszkodyButton.Text = 'Stworz przeszkody';
            app.NarysujprzeszkodyButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.NarysujprzeszkodyButton.ButtonPushedFcn = createCallbackFcn(app, @NarysujprzeszkodyButtonPushed, true);
            app.NarysujprzeszkodyButton.Text = 'Narysuj przeszkody';
            app.ileLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.ileLabel.Text = 'ile';
            app.Srty_2 = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.Srty_2.Limits = [1 Inf];
            app.Srty_2.RoundFractionalValues = 'on';
            app.Srty_2.Value = 1;
            app.WyczyButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.WyczyButton.ButtonPushedFcn = createCallbackFcn(app, @WyczyButtonPushed, true);
            app.WyczyButton.Text = 'Wyczyść';
            app.pokapozycjeButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.pokapozycjeButton.ButtonPushedFcn = createCallbackFcn(app, @pokapozycjeButtonPushed, true);
            app.pokapozycjeButton.Text = 'pokaż pozycje';
            app.KolejnaiteracjaButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.KolejnaiteracjaButton.ButtonPushedFcn = createCallbackFcn(app, @KolejnaiteracjaButtonPushed, true);
            app.KolejnaiteracjaButton.Text = 'Kolejna iteracja';
            app.PokazdrogeButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.PokazdrogeButton.ButtonPushedFcn = createCallbackFcn(app, @PokazdrogeButtonPushed, true);
            app.PokazdrogeButton.Text = 'Pokaz droge';
            app.Label_2 = uilabel(app.OmijanieprzeszkdkulTab);
            app.Label_2.Text = '';
            app.EditField = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.Value = 1;
            app.EditField_2Label = uilabel(app.OmijanieprzeszkdkulTab);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.EditField_2.Value = 1;
            app.drogaEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.drogaEditFieldLabel.Text = 'droga';
            app.drogaEditField = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.drogaEditField.Limits = [1 Inf];
            app.drogaEditField.Value = 1;
            app.testButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Enable = 'off';
            app.testButton.Text = 'test';
            app.ZapisButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.ZapisButton.ButtonPushedFcn = createCallbackFcn(app, @ZapisButtonPushed, true);
            app.ZapisButton.Text = 'Zapis';
            app.WczytajButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Text = 'Wczytaj';
            app.Label_3 = uilabel(app.OmijanieprzeszkdkulTab);
            app.Label_3.Text = '';
            app.EditField_5 = uieditfield(app.OmijanieprzeszkdkulTab, 'text');
            app.EditField_5.Value = 'Nazwa';
            app.TabGroup2 = uitabgroup(app.OmijanieprzeszkdkulTab);
            app.ObiektTab = uitab(app.TabGroup2);
            app.ObiektTab.Title = 'Obiekt';
            app.skretEditFieldLabel = uilabel(app.ObiektTab);
            app.skretEditFieldLabel.Text = 'skret';
            app.skretEditField = uieditfield(app.ObiektTab, 'numeric');
            app.skretEditField.Limits = [0 Inf];
            app.skretEditField.Value = 0.785;
            app.MaksymalneprzypieszenieEditFieldLabel = uilabel(app.ObiektTab);
            app.MaksymalneprzypieszenieEditFieldLabel.Text = 'Maksymalne przyśpieszenie';
            app.MaksymalneprzypieszenieEditField = uieditfield(app.ObiektTab, 'numeric');
            app.MaksymalneprzypieszenieEditField.Limits = [0 1];
            app.MaksymalneprzypieszenieEditField.Value = 0.2;
            app.WykrywanieprzeszkdSpinnerLabel = uilabel(app.ObiektTab);
            app.WykrywanieprzeszkdSpinnerLabel.Text = 'Wykrywanie przeszkód';
            app.WykrywanieprzeszkdSpinner = uispinner(app.ObiektTab);
            app.WykrywanieprzeszkdSpinner.Step = 0.1;
            app.WykrywanieprzeszkdSpinner.Limits = [0 5];
            app.WykrywanieprzeszkdSpinner.Value = 3;
            app.NiebracpoduwagewlasnejpredkosciCheckBox = uicheckbox(app.ObiektTab);
            app.NiebracpoduwagewlasnejpredkosciCheckBox.Text = 'Nie brac pod uwage wlasnej predkosci';
            app.NiebracpoduwagewlasnejpredkosciCheckBox.Value = true;
            app.IteracjenasekundEditFieldLabel = uilabel(app.ObiektTab);
            app.IteracjenasekundEditFieldLabel.Text = 'Iteracje na sekundę';
            app.IteracjenasekundEditField = uieditfield(app.ObiektTab, 'numeric');
            app.IteracjenasekundEditField.Limits = [0 Inf];
            app.IteracjenasekundEditField.Value = 5;
            app.SztucznepolepotencjawTab = uitab(app.TabGroup2);
            app.SztucznepolepotencjawTab.Title = 'Sztuczne pole potencjałów';
            app.WzmocnienieprzyciganiaEditFieldLabel = uilabel(app.SztucznepolepotencjawTab);
            app.WzmocnienieprzyciganiaEditFieldLabel.Text = 'Wzmocnienie przyciągania';
            app.WzmocnienieprzyciganiaEditField = uieditfield(app.SztucznepolepotencjawTab, 'numeric');
            app.WzmocnienieprzyciganiaEditField.Limits = [0 Inf];
            app.WzmocnienieprzyciganiaEditField.Value = 1;
            app.WzmocnienieodpychaniaEditFieldLabel = uilabel(app.SztucznepolepotencjawTab);
            app.WzmocnienieodpychaniaEditFieldLabel.Text = 'Wzmocnienie odpychania';
            app.WzmocnienieodpychaniaEditField = uieditfield(app.SztucznepolepotencjawTab, 'numeric');
            app.WzmocnienieodpychaniaEditField.Value = 0.6;
            app.LogikarozmytaTab = uitab(app.TabGroup2);
            app.LogikarozmytaTab.Title = 'Logika rozmyta';
            app.metodaandDropDownLabel = uilabel(app.LogikarozmytaTab);
            app.metodaandDropDownLabel.Text = 'metoda and';
            app.metodaandDropDown = uidropdown(app.LogikarozmytaTab);
            app.metodaandDropDown.Items = {'min', 'prod'};
            app.metodaandDropDown.Value = 'min';
            app.OdrozmywanieDropDownLabel = uilabel(app.LogikarozmytaTab);
            app.OdrozmywanieDropDownLabel.Text = 'Odrozmywanie';
            app.OdrozmywanieDropDown = uidropdown(app.LogikarozmytaTab);
            app.OdrozmywanieDropDown.Items = {'centroid', 'bisector', 'mom', 'lom', 'som'};
            app.OdrozmywanieDropDown.Value = 'centroid';
            app.ATab = uitab(app.TabGroup2);
            app.ATab.Title = 'A*';
            app.DropDown_2Label = uilabel(app.ATab);
            app.DropDown_2Label.Text = 'Drop Down';
            app.DropDown_2 = uidropdown(app.ATab);
            app.DropDown_2.Items = {'dikstrja', 'astar', 'Option 3', 'Option 4'};
            app.DropDown_2.Value = 'astar';
            app.BezpiecznaodlegoEditFieldLabel = uilabel(app.ATab);
            app.BezpiecznaodlegoEditFieldLabel.Text = 'Bezpieczna odległość';
            app.BezpiecznaodlegoEditField = uieditfield(app.ATab, 'numeric');
            app.BezpiecznaodlegoEditField.Limits = [0 Inf];
            app.BezpiecznaodlegoEditField.Value = 0.2;
            app.Wykres = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.Wykres.ButtonPushedFcn = createCallbackFcn(app, @WykresButtonPushed, true);
            app.Wykres.Text = 'Wykres odległości od najbliższej przeszkody';
            app.CzasobliczeredniEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.CzasobliczeredniEditFieldLabel.Text = 'Czas obliczeń średni';
            app.CzasobliczeredniEditField = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.imaksymalnyEditFieldLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.imaksymalnyEditFieldLabel.Text = 'i maksymalny';
            app.imaksymalnyEditField = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.DodajprzeszkodyButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.DodajprzeszkodyButton.ButtonPushedFcn = createCallbackFcn(app, @DodajprzeszkodyButtonPushed, true);
            app.DodajprzeszkodyButton.Text = 'Dodaj przeszkody';
            app.ileLabel_2 = uilabel(app.OmijanieprzeszkdkulTab);
            app.ileLabel_2.Text = 'ile';
            app.Srty_4 = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.Srty_4.Limits = [1 Inf];
            app.Srty_4.RoundFractionalValues = 'on';
            app.Srty_4.Value = 1;
            app.UsuwierszButton = uibutton(app.OmijanieprzeszkdkulTab, 'push');
            app.UsuwierszButton.ButtonPushedFcn = createCallbackFcn(app, @UsuwierszButtonPushed, true);
            app.UsuwierszButton.Text = 'Usuń wiersz';
            app.jakiLabel = uilabel(app.OmijanieprzeszkdkulTab);
            app.jakiLabel.Text = 'jaki';
            app.Srty_5 = uieditfield(app.OmijanieprzeszkdkulTab, 'numeric');
            app.Srty_5.Limits = [1 Inf];
            app.Srty_5.RoundFractionalValues = 'on';
            app.Srty_5.Value = 1;
            app.OmijanieinnychUAVTab = uitab(app.TabGroup);
            app.OmijanieinnychUAVTab.Title = 'Omijanie innych UAV';
            app.UIAxes_2 = uiaxes(app.OmijanieinnychUAVTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UITable_2 = uitable(app.OmijanieinnychUAVTab);
            app.UITable_2.ColumnName = {'lp'; 'Start x'; 'Start y'; 'Start z'; 'koniec x'; 'Koniec y'; 'Koniec z'; 'Metoda'; 'ro'; 'skret'; 'przyspieszenie'; 'parametr1'; 'parametr2'; 'Czy brac pod uwage szybkosc'; 'Dystans'; 'Najmniejsza odległość do przeszkody'; 'Ilość iteracji'; 'Średni czas iteracji'; 'Maksymalny czas iteracji'; 'Kolor'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [true true true true true true true true true true true true true true false false false false false true];
            app.StartxEditFieldLabel_2 = uilabel(app.OmijanieinnychUAVTab);
            app.StartxEditFieldLabel_2.Text = 'Start x';
            app.Srtx_2 = uieditfield(app.OmijanieinnychUAVTab, 'numeric');
            app.yEditFieldLabel_2 = uilabel(app.OmijanieinnychUAVTab);
            app.yEditFieldLabel_2.Text = 'y';
            app.Srty_3 = uieditfield(app.OmijanieinnychUAVTab, 'numeric');
            app.zEditFieldLabel_2 = uilabel(app.OmijanieinnychUAVTab);
            app.zEditFieldLabel_2.Text = 'z';
            app.Srtz_2 = uieditfield(app.OmijanieinnychUAVTab, 'numeric');
            app.KoniecxEditFieldLabel_2 = uilabel(app.OmijanieinnychUAVTab);
            app.KoniecxEditFieldLabel_2.Text = 'Koniec x';
            app.kncx_2 = uieditfield(app.OmijanieinnychUAVTab, 'numeric');
            app.kncx_2.Value = 10;
            app.yEditField_2Label_2 = uilabel(app.OmijanieinnychUAVTab);
            app.yEditField_2Label_2.Text = 'y';
            app.kncy_2 = uieditfield(app.OmijanieinnychUAVTab, 'numeric');
            app.kncy_2.Value = 10;
            app.zEditField_2Label_2 = uilabel(app.OmijanieinnychUAVTab);
            app.zEditField_2Label_2.Text = 'z';
            app.kncz_2 = uieditfield(app.OmijanieinnychUAVTab, 'numeric');
            app.kncz_2.Value = 10;
            app.PrognozaCheckBox_2 = uicheckbox(app.OmijanieinnychUAVTab);
            app.PrognozaCheckBox_2.Text = 'Prognoza';
            app.DropDown_4Label = uilabel(app.OmijanieinnychUAVTab);
            app.DropDown_4Label.Text = '';
            app.DropDown_4 = uidropdown(app.OmijanieinnychUAVTab);
            app.DropDown_4.Items = {'min', 'prod'};
            app.DropDown_4.Value = 'min';
            app.DodajobiektButton = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.DodajobiektButton.ButtonPushedFcn = createCallbackFcn(app, @DodajobiektButtonPushed, true);
            app.DodajobiektButton.Text = 'Dodaj obiekt';
            app.OblicztrajektorieButton_2 = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.OblicztrajektorieButton_2.ButtonPushedFcn = createCallbackFcn(app, @OblicztrajektorieButton_2Pushed, true);
            app.OblicztrajektorieButton_2.Text = 'Oblicz trajektorie';
            app.kolorDropDown_2Label = uilabel(app.OmijanieinnychUAVTab);
            app.kolorDropDown_2Label.Text = 'kolor';
            app.kolorDropDown_2 = uidropdown(app.OmijanieinnychUAVTab);
            app.kolorDropDown_2.Items = {'r', 'y', 'm', 'g', 'c', 'b', 'w', 'k'};
            app.kolorDropDown_2.Value = 'r';
            app.WyczyButton_2 = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.WyczyButton_2.ButtonPushedFcn = createCallbackFcn(app, @WyczyButton_2Pushed, true);
            app.WyczyButton_2.Text = 'Wyczyść';
            app.pokapozycjeButton_2 = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.pokapozycjeButton_2.ButtonPushedFcn = createCallbackFcn(app, @pokapozycjeButton_2Pushed, true);
            app.pokapozycjeButton_2.Text = 'pokaż pozycje';
            app.KolejnaiteracjaButton_2 = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.KolejnaiteracjaButton_2.ButtonPushedFcn = createCallbackFcn(app, @KolejnaiteracjaButton_2Pushed, true);
            app.KolejnaiteracjaButton_2.Text = 'Kolejna iteracja';
            app.EditField_3Label = uilabel(app.OmijanieinnychUAVTab);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.OmijanieinnychUAVTab, 'numeric');
            app.EditField_3.Limits = [1 Inf];
            app.EditField_3.Value = 1;
            app.EditField_4Label = uilabel(app.OmijanieinnychUAVTab);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.OmijanieinnychUAVTab, 'numeric');
            app.EditField_4.Value = 1;
            app.ZapisButton_2 = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.ZapisButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZapisButton_2Pushed, true);
            app.ZapisButton_2.Text = 'Zapis';
            app.WczytajButton_2 = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.WczytajButton_2.ButtonPushedFcn = createCallbackFcn(app, @WczytajButton_2Pushed, true);
            app.WczytajButton_2.Text = 'Wczytaj';
            app.EditField_6Label = uilabel(app.OmijanieinnychUAVTab);
            app.EditField_6Label.Text = '';
            app.EditField_6 = uieditfield(app.OmijanieinnychUAVTab, 'text');
            app.EditField_6.Value = 'Nazwa';
            app.LosujButton = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.LosujButton.ButtonPushedFcn = createCallbackFcn(app, @LosujButtonPushed, true);
            app.LosujButton.Text = 'Losuj';
            app.Label_5 = uilabel(app.OmijanieinnychUAVTab);
            app.Label_5.Text = '';
            app.DropDown_3 = uidropdown(app.OmijanieinnychUAVTab);
            app.DropDown_3.Items = {'dikstrja', 'astar', 'Option 3', 'Option 4'};
            app.DropDown_3.Value = 'astar';
            app.NiebracpoduwagewlasnejpredkosciCheckBox_2 = uicheckbox(app.OmijanieinnychUAVTab);
            app.NiebracpoduwagewlasnejpredkosciCheckBox_2.Text = 'Nie brac pod uwage wlasnej predkosci';
            app.NiebracpoduwagewlasnejpredkosciCheckBox_2.Value = true;
            app.UsunwierszButton = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.UsunwierszButton.ButtonPushedFcn = createCallbackFcn(app, @UsunwierszButtonPushed, true);
            app.UsunwierszButton.Text = 'Usun wiersz';
            app.Label_4 = uilabel(app.OmijanieinnychUAVTab);
            app.Label_4.Text = '';
            app.EditField_7 = uieditfield(app.OmijanieinnychUAVTab, 'numeric');
            app.Button = uibutton(app.OmijanieinnychUAVTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TabGroup3 = uitabgroup(app.OmijanieinnychUAVTab);
            app.OglneTab = uitab(app.TabGroup3);
            app.OglneTab.Title = 'Ogólne';
            app.minodlegomidzyuavEditFieldLabel = uilabel(app.OglneTab);
            app.minodlegomidzyuavEditFieldLabel.Text = 'min odległość między uav';
            app.minodlegomidzyuavEditField = uieditfield(app.OglneTab, 'numeric');
            app.minodlegomidzyuavEditField.Limits = [0 Inf];
            app.minodlegomidzyuavEditField.Value = 1;
            app.skretEditField_2Label = uilabel(app.OglneTab);
            app.skretEditField_2Label.Text = 'skret';
            app.skretEditField_2 = uieditfield(app.OglneTab, 'numeric');
            app.skretEditField_2.Limits = [0 Inf];
            app.skretEditField_2.Value = 0.785;
            app.MaksymalneprzypieszenieEditField_2Label = uilabel(app.OglneTab);
            app.MaksymalneprzypieszenieEditField_2Label.Text = 'Maksymalne przyśpieszenie';
            app.MaksymalneprzypieszenieEditField_2 = uieditfield(app.OglneTab, 'numeric');
            app.MaksymalneprzypieszenieEditField_2.Limits = [0 1];
            app.MaksymalneprzypieszenieEditField_2.Value = 0.2;
            app.IteracjenasekundEditField_2Label = uilabel(app.OglneTab);
            app.IteracjenasekundEditField_2Label.Text = 'Iteracje na sekundę';
            app.IteracjenasekundEditField_2 = uieditfield(app.OglneTab, 'numeric');
            app.IteracjenasekundEditField_2.Limits = [0 Inf];
            app.IteracjenasekundEditField_2.Value = 5;
            app.MetodaDropDownLabel_2 = uilabel(app.OglneTab);
            app.MetodaDropDownLabel_2.Text = 'Metoda';
            app.Metoda_2 = uidropdown(app.OglneTab);
            app.Metoda_2.Items = {'potential fields', 'shortest road', 'logika rozmyta'};
            app.Metoda_2.Value = 'potential fields';
            app.WykrywanieprzeszkdSpinner_2Label = uilabel(app.OglneTab);
            app.WykrywanieprzeszkdSpinner_2Label.Text = 'Wykrywanie przeszkód';
            app.WykrywanieprzeszkdSpinner_2 = uispinner(app.OglneTab);
            app.WykrywanieprzeszkdSpinner_2.Step = 0.1;
            app.WykrywanieprzeszkdSpinner_2.Limits = [0 5];
            app.WykrywanieprzeszkdSpinner_2.Value = 3;
            app.SztucznepolapotencjawTab = uitab(app.TabGroup3);
            app.SztucznepolapotencjawTab.Title = 'Sztuczne pola potencjałów';
            app.WzmocnienieodpychaniaEditField_2Label = uilabel(app.SztucznepolapotencjawTab);
            app.WzmocnienieodpychaniaEditField_2Label.Text = 'Wzmocnienie odpychania';
            app.WzmocnienieodpychaniaEditField_2 = uieditfield(app.SztucznepolapotencjawTab, 'numeric');
            app.WzmocnienieodpychaniaEditField_2.Value = 0.6;
            app.WzmocnienieprzyciganiaEditField_2Label = uilabel(app.SztucznepolapotencjawTab);
            app.WzmocnienieprzyciganiaEditField_2Label.Text = 'Wzmocnienie przyciągania';
            app.WzmocnienieprzyciganiaEditField_2 = uieditfield(app.SztucznepolapotencjawTab, 'numeric');
            app.WzmocnienieprzyciganiaEditField_2.Limits = [0 Inf];
            app.WzmocnienieprzyciganiaEditField_2.Value = 1;
            app.logikarozmytaTab = uitab(app.TabGroup3);
            app.logikarozmytaTab.Title = 'logika rozmyta';
            app.OdrozmywanieDropDown_2Label = uilabel(app.logikarozmytaTab);
            app.OdrozmywanieDropDown_2Label.Text = 'Odrozmywanie';
            app.OdrozmywanieDropDown_2 = uidropdown(app.logikarozmytaTab);
            app.OdrozmywanieDropDown_2.Items = {'centroid', 'bisector', 'mom', 'lom', 'som'};
            app.OdrozmywanieDropDown_2.Value = 'centroid';
            app.ATab_2 = uitab(app.TabGroup3);
            app.ATab_2.Title = 'A*';
            app.BezpiecznaodlegoEditField_2Label = uilabel(app.ATab_2);
            app.BezpiecznaodlegoEditField_2Label.Text = 'Bezpieczna odległość';
            app.BezpiecznaodlegoEditField_2 = uieditfield(app.ATab_2, 'numeric');
            app.BezpiecznaodlegoEditField_2.Limits = [0 Inf];
            app.BezpiecznaodlegoEditField_2.Value = 0.2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end